classdef About < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Panel_3                  matlab.ui.container.Panel
        HyperlinkWebsite         matlab.ui.control.Hyperlink
        WebsiteLabel             matlab.ui.control.Label
        SupportLabel             matlab.ui.control.Label
        HyperlinkEmail           matlab.ui.control.Hyperlink
        DevelopersTextArea       matlab.ui.control.TextArea
        DevelopersTextAreaLabel  matlab.ui.control.Label
        UpdatedEditField         matlab.ui.control.EditField
        UpdatedEditFieldLabel    matlab.ui.control.Label
        VersionEditField         matlab.ui.control.EditField
        VersionEditFieldLabel    matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        ULTRASATObservationPlannerLabel  matlab.ui.control.Label
    end

    methods (Static)
        function about()
            % About App
            %
            % This app displays information about the ULTRASAT Observation Planner,
            % including version details, update history, developers, and support links.
            %
            % Features:
            % - Shows the current version and last update date.
            % - Lists the developers of the project.
            % - Provides direct hyperlinks to the project website and support email.
            % - Displays the project and institution logos.
        end
    end

    properties (Access = public)
        MainModule      % Reference to the main application module
        Status          % Status of the operation ('Save' or 'Cancel')
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
            app.UIFigure.Name = 'About';
            app.MainModule.AppUtils.center(app);
        end

        % Callback function: HyperlinkWebsite
        function HyperlinkWebsiteClicked(app, event)
            web(app.HyperlinkWebsite.Value);
        end

        % Callback function: HyperlinkEmail
        function HyperlinkEmailClicked(app, event)
            web(app.HyperlinkEmail.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ULTRASATObservationPlannerLabel = uilabel(app.Panel_2);
            app.ULTRASATObservationPlannerLabel.Text = 'ULTRASAT Observation Planner';
            app.Panel_3 = uipanel(app.UIFigure);
            app.VersionEditFieldLabel = uilabel(app.Panel_3);
            app.VersionEditFieldLabel.Text = 'Version';
            app.VersionEditField = uieditfield(app.Panel_3, 'text');
            app.VersionEditField.Editable = 'off';
            app.VersionEditField.Value = '0.2a (DB)';
            app.UpdatedEditFieldLabel = uilabel(app.Panel_3);
            app.UpdatedEditFieldLabel.Text = 'Updated';
            app.UpdatedEditField = uieditfield(app.Panel_3, 'text');
            app.UpdatedEditField.Editable = 'off';
            app.UpdatedEditField.Value = '19/02/2026';
            app.DevelopersTextAreaLabel = uilabel(app.Panel_3);
            app.DevelopersTextAreaLabel.Text = 'Developers';
            app.DevelopersTextArea = uitextarea(app.Panel_3);
            app.DevelopersTextArea.Editable = 'off';
            app.DevelopersTextArea.Value = {'Chen Tishler'; 'Yossi Shvartzvald'; 'Sasha Krassilchtchikov'; 'Eran Ofek'};
            app.HyperlinkEmail = uihyperlink(app.Panel_3);
            app.HyperlinkEmail.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkEmailClicked, true);
            app.HyperlinkEmail.URL = 'mailto:planner@ultrasatsoc.org';
            app.HyperlinkEmail.Text = 'mailto:planner@ultrasatsoc.org';
            app.SupportLabel = uilabel(app.Panel_3);
            app.SupportLabel.Text = 'Support';
            app.WebsiteLabel = uilabel(app.Panel_3);
            app.WebsiteLabel.Text = 'Website';
            app.HyperlinkWebsite = uihyperlink(app.Panel_3);
            app.HyperlinkWebsite.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkWebsiteClicked, true);
            app.HyperlinkWebsite.URL = 'https://www.weizmann.ac.il/ultrasat/';
            app.HyperlinkWebsite.Text = 'https://www.weizmann.ac.il/ultrasat/';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'PlannerLogo1.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'WisLogo1.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = About(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end