classdef AddUniqueTarget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel_3               matlab.ui.container.Panel
        DecEditField          matlab.ui.control.EditField
        DecEditFieldLabel     matlab.ui.control.Label
        RAEditField           matlab.ui.control.EditField
        RAEditFieldLabel      matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        AddUniqueTargetLabel  matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        CancelButton          matlab.ui.control.Button
        AddButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule
        Status
        Index
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;

            if isempty(app.Index)
                app.Index = 0; % Set to 0 if empty
            else
                app.Index = app.Index + 1; % Increment if not empty
            end            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.Status = 'Add';
            uiresume(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AddUniqueTargetLabel = uilabel(app.Panel_2);
            app.AddUniqueTargetLabel.Text = 'Add Unique Target';
            app.Panel_3 = uipanel(app.UIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel_3);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel_3, 'text');
            app.NameEditField.Value = 'MyUniqueTarget';
            app.RAEditFieldLabel = uilabel(app.Panel_3);
            app.RAEditFieldLabel.Text = 'RA';
            app.RAEditField = uieditfield(app.Panel_3, 'text');
            app.RAEditField.Value = '215';
            app.DecEditFieldLabel = uilabel(app.Panel_3);
            app.DecEditFieldLabel.Text = 'Dec';
            app.DecEditField = uieditfield(app.Panel_3, 'text');
            app.DecEditField.Value = '60';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddUniqueTarget(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end