classdef AdjustGroupStartTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        AdjustButtonGroup             matlab.ui.container.ButtonGroup
        StartTimeEditField            matlab.ui.control.EditField
        YYYYMMDDHHMMSSEditFieldLabel  matlab.ui.control.Label
        ShiftTimeEditField            matlab.ui.control.EditField
        HHMMSSLabel                   matlab.ui.control.Label
        NewStartTimeButton            matlab.ui.control.RadioButton
        ShiftTimeButton               matlab.ui.control.RadioButton
        RelativeButton                matlab.ui.control.RadioButton
        GroupDropDown                 matlab.ui.control.DropDown
        GroupDropDownLabel            matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        AdjustGroupStartTimeLabel     matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule          % 
        Status              %
        Mode                % Relative, StartTime, Shift
        GroupList           % 
        StartTime           %
        ShiftTime           %
    end
    
    methods (Access = public)

        function beforeShow(app)
            % Called from PlannerMain.showModal()
            app.GroupDropDown.Value = 'All';
            %app.GroupDropDownValueChanged();
            
        end
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
        end

        % Button pushed function: OKButton
        function SaveButtonPushed(app, event)
            app.Status = [];
            try
                if app.RelativeButton.Value
                    app.Mode = 'Relative';
                elseif app.ShiftTimeButton.Value
                    app.Mode = 'Shift';
                    app.ShiftTime = duration(app.ShiftTimeEditField.Value);
                elseif app.NewStartTimeButton.Value
                    app.Mode = 'StartTime';                    
                    app.StartTime = datetime(app.StartTimeEditField.Value, 'TimeZone', 'UTC');
                end
                app.Status = 'OK';
                uiresume(app.UIFigure);
            catch ME
                AppUtils.msgError(app, ME.message);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end

        % Value changed function: GroupDropDown
        function GroupDropDownValueChanged(app, event)
            value = app.GroupDropDown.Value;
            if strcmp(value, 'All')
                app.GroupList = [];
                app.NewStartTimeButton.Enable = 'off';
                app.StartTimeEditField.Enable = 'off';
            else
                app.GroupList = [str2double(value)];
                app.NewStartTimeButton.Enable = 'on';
                app.StartTimeEditField.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AdjustGroupStartTimeLabel = uilabel(app.Panel_2);
            app.AdjustGroupStartTimeLabel.Text = 'Adjust Group Start Time';
            app.GroupDropDownLabel = uilabel(app.UIFigure);
            app.GroupDropDownLabel.Text = 'Group';
            app.GroupDropDown = uidropdown(app.UIFigure);
            app.GroupDropDown.Items = {'All', '1', '2', '3'};
            app.GroupDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupDropDownValueChanged, true);
            app.GroupDropDown.Value = 'All';
            app.AdjustButtonGroup = uibuttongroup(app.UIFigure);
            app.AdjustButtonGroup.Title = 'Adjust';
            app.RelativeButton = uiradiobutton(app.AdjustButtonGroup);
            app.RelativeButton.Text = 'Relative to mission approved targets (requires Retreive Approved Targets)';
            app.RelativeButton.Value = true;
            app.ShiftTimeButton = uiradiobutton(app.AdjustButtonGroup);
            app.ShiftTimeButton.Text = 'Shift time by';
            app.NewStartTimeButton = uiradiobutton(app.AdjustButtonGroup);
            app.NewStartTimeButton.Text = 'New start time';
            app.HHMMSSLabel = uilabel(app.AdjustButtonGroup);
            app.HHMMSSLabel.Text = '+/- HH:MM:SS';
            app.ShiftTimeEditField = uieditfield(app.AdjustButtonGroup, 'text');
            app.ShiftTimeEditField.Value = '00:05:00';
            app.YYYYMMDDHHMMSSEditFieldLabel = uilabel(app.AdjustButtonGroup);
            app.YYYYMMDDHHMMSSEditFieldLabel.Text = 'YYYY-MM-DD HH:MM:SS';
            app.StartTimeEditField = uieditfield(app.AdjustButtonGroup, 'text');
            app.StartTimeEditField.Value = '2028-01-01 12:00:00';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'When selecting All Groups, only Relative and Shift options are applicable.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustGroupStartTime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end