classdef DuplicatePlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_3                         matlab.ui.container.Panel
        UserNameEditField               matlab.ui.control.EditField
        UserNameEditFieldLabel          matlab.ui.control.Label
        PlanTitleEditField              matlab.ui.control.EditField
        PlanTitleEditFieldLabel         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        DuplicateObservingProgramLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CancelButton                    matlab.ui.control.Button
        DuplicateButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule
        Status
        Index
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;

            if isempty(app.Index)
                app.Index = 0; % Set to 0 if empty
            else
                app.Index = app.Index + 1; % Increment if not empty
            end            
        end

        % Button pushed function: DuplicateButton
        function DuplicateButtonPushed(app, event)
            app.Status = 'Add';
            uiresume(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.DuplicateButton = uibutton(app.Panel, 'push');
            app.DuplicateButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateButtonPushed, true);
            app.DuplicateButton.Text = 'Duplicate';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.DuplicateObservingProgramLabel = uilabel(app.Panel_2);
            app.DuplicateObservingProgramLabel.Text = 'Duplicate Observing Program';
            app.Panel_3 = uipanel(app.UIFigure);
            app.PlanTitleEditFieldLabel = uilabel(app.Panel_3);
            app.PlanTitleEditFieldLabel.Text = 'Plan Title';
            app.PlanTitleEditField = uieditfield(app.Panel_3, 'text');
            app.PlanTitleEditField.Value = 'MyUniqueTarget';
            app.UserNameEditFieldLabel = uilabel(app.Panel_3);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UserNameEditField = uieditfield(app.Panel_3, 'text');
            app.UserNameEditField.Value = 'MyUniqueTarget';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DuplicatePlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end