classdef EnterStartTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GroupEditField                matlab.ui.control.EditField
        GroupNumberLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        GroupStartTimeEditField       matlab.ui.control.EditField
        GroupStartTimeEditFieldLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        EnterDDTStartTimeLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule          % 
        Status              %
        StartTime           %
        StartDT             %
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
        end

        % Button pushed function: OKButton
        function SaveButtonPushed(app, event)
            app.Status = [];
            app.StartTime = [];
            app.StartDT = [];

            Timestamp = app.GroupStartTimeEditField.Value;
            try
                app.StartDT = datetime(Timestamp);
                app.StartTime = Timestamp;
                app.Status = 'OK';
                uiresume(app.UIFigure);
            catch ME
                % Show message 'Invalid time format, example: 2028-01-01 12:00:00');
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.EnterDDTStartTimeLabel = uilabel(app.Panel_2);
            app.EnterDDTStartTimeLabel.Text = 'Enter DDT Start Time';
            app.GroupStartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.GroupStartTimeEditFieldLabel.Text = 'Group Start Time';
            app.GroupStartTimeEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GroupNumberLabel = uilabel(app.UIFigure);
            app.GroupNumberLabel.Text = 'Group Number';
            app.GroupEditField = uieditfield(app.UIFigure, 'text');
            app.GroupEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnterStartTime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end