classdef LoadPlanFromFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BrowseButton            matlab.ui.control.Button
        EnterfilenametoloadfromorpastetextbelowLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        LoadPlanFromFileLabel   matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        CancelButton            matlab.ui.control.Button
        LoadButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule          % Refrence to PlannerMain app
        Status              % Status string returned when the form is closed, according to the button clicked
        FileName            % Selected filename
        Folder              % Selected folder
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.Status = [];
            app.FileName = [];

            FName = app.FileNameEditField.Value;
            if ~isempty(FName)
                if isfile(FName)
                    app.FileName = FName;
                    app.Status = 'Load';
                    uiresume(app.UIFigure);
                else
                    AppUtils.msgError(app, 'Cannot find the specified file', 'File not found')
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile(...
                {'*.mat', 'MAT Files (*.mat)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Select a File', app.Folder);

            if file ~= 0
                app.FileNameEditField.Value = fullfile(path, file);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadPlanFromFileLabel = uilabel(app.Panel_2);
            app.LoadPlanFromFileLabel.Text = 'Load Plan From File';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'C:/AstroPack/data/ULTRASAT/my_plan_1.mat';
            app.EnterfilenametoloadfromorpastetextbelowLabel = uilabel(app.UIFigure);
            app.EnterfilenametoloadfromorpastetextbelowLabel.Text = 'Plan will be loaded from .mat file as single MATLAB object.';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadPlanFromFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end