classdef NewPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlanParametersPanel            matlab.ui.container.Panel
        PlannerNameEditField           matlab.ui.control.EditField
        PlannerNameEditFieldLabel      matlab.ui.control.Label
        EndTimeEditField               matlab.ui.control.EditField
        EndTimeEditFieldLabel          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.EditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        CreateNewObservationPlanLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        PlanTypeButtonGroup            matlab.ui.container.ButtonGroup
        TOOButton                      matlab.ui.control.RadioButton
        AllSSButton                    matlab.ui.control.RadioButton
        DDTButton                      matlab.ui.control.RadioButton
        LCSButton                      matlab.ui.control.RadioButton
        HCSButton                      matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        MainModule          % 
        Status              % 'Cancel', 'Save'
        PlanType            %
        Index               %
    end
    

    methods (Access = public)
        function beforeShow(app)
            % Called from PlannerMain.showModal()
            if isempty(app.PlannerNameEditField.Value)
                app.CreateButton.Enable = false;
            else
                app.CreateButton.Enable = true; 
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
            AppUtils.center(app);

            if isempty(app.Index)
                app.Index = 1; % Set to 0 if empty
            else
                app.Index = app.Index + 1; % Increment if not empty
            end

            % Set default values for fields? here or by caller?
            app.TitleEditField.Value = sprintf('MyPlan_%d', app.Index);
            app.StartTimeEditField.Value = '2028-01-01 12:00:00';
            app.EndTimeEditField.Value = '2028-07-01 12:00:00';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            %

            app.PlanType = [];                                   
            if app.HCSButton.Value
                app.PlanType = 'HCS';
            end
            if app.LCSButton.Value
                app.PlanType = 'LCS';                
            end
            if app.DDTButton.Value
                app.PlanType = 'DDT';                
            end
            if app.AllSSButton.Value
                app.PlanType = 'AllSS';
            end
            if app.TOOButton.Value
                app.PlanType = 'TOO';
            end           

            app.Status = 'Create';
            uiresume(app.UIFigure);
        end

        % Value changed function: PlannerNameEditField
        function PlannerNameEditFieldValueChanged(app, event)
            value = app.PlannerNameEditField.Value;
            value = strip(value);
            app.CreateButton.Enable = ~isempty(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlanTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlanTypeButtonGroup.Title = 'Plan Type';
            app.HCSButton = uiradiobutton(app.PlanTypeButtonGroup);
            app.HCSButton.Text = 'HCS';
            app.HCSButton.Value = true;
            app.LCSButton = uiradiobutton(app.PlanTypeButtonGroup);
            app.LCSButton.Text = 'LCS';
            app.DDTButton = uiradiobutton(app.PlanTypeButtonGroup);
            app.DDTButton.Text = 'DDT';
            app.AllSSButton = uiradiobutton(app.PlanTypeButtonGroup);
            app.AllSSButton.Text = 'AllSS';
            app.TOOButton = uiradiobutton(app.PlanTypeButtonGroup);
            app.TOOButton.Text = 'TOO';
            app.Panel = uipanel(app.UIFigure);
            app.CreateButton = uibutton(app.Panel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.CreateNewObservationPlanLabel = uilabel(app.Panel_2);
            app.CreateNewObservationPlanLabel.Text = 'Create New Observation Plan';
            app.PlanParametersPanel = uipanel(app.UIFigure);
            app.PlanParametersPanel.Title = 'Plan Parameters';
            app.TitleEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.StartTimeEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.EndTimeEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.PlannerNameEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.PlannerNameEditFieldLabel.Text = 'Planner Name';
            app.PlannerNameEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.PlannerNameEditField.ValueChangedFcn = createCallbackFcn(app, @PlannerNameEditFieldValueChanged, true);
            app.PlannerNameEditField.Placeholder = 'Planner name, press <Enter> to apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end