classdef OpenPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FilterPanel               matlab.ui.container.Panel
        PlanTitleEditField        matlab.ui.control.EditField
        PlanTitleEditFieldLabel   matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        EndTimeEditField          matlab.ui.control.EditField
        EndTimeEditFieldLabel     matlab.ui.control.Label
        StartTimeEditField        matlab.ui.control.EditField
        StartTimeEditFieldLabel   matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        Panel_2                   matlab.ui.container.Panel
        OpenObservationPlanLabel  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        CancelButton              matlab.ui.control.Button
        OpenButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule      %
        Status          %
        Pk              %
    end


    methods (Access = public)

        function setup(app)
            app.UITable.SelectionType = "row";
            app.UITable.Multiselect = "off";            
            app.UITable.RowName = "numbered";

            % Load the initial list
            app.getList();
        end
        

        function getList(app)
            % Get search parameters from UI fields
            start_time = app.StartTimeEditField.Value;
            end_time = app.EndTimeEditField.Value;
            title_subtext = app.PlanTitleEditField.Value;
            
            % Convert empty fields to [] so API gets empty values if not provided
            if isempty(start_time)
                start_time = [];
            end
            if isempty(end_time)
                end_time = [];
            end
            if isempty(title_subtext)
                title_subtext = [];
            end
        
            % Fetch the plans list from API
            try
                response = app.MainModule.ApiClient.getPlansList(start_time, end_time, title_subtext);            
            catch ME
                uialert(app.UIFigure, sprintf('Failed to retrieve plans list: %s', ME.message), 'Error');
                return;
            end

            if ~response.ok
                % @Todo Show alert (use msgbox or uialert)
                uialert(app.UIFigure, 'Failed to retrieve plans list', 'Error');
                return;
            end
            
            % Convert struct array to table if not empty
            if ~isempty(response.plans)
                Data = struct2table(response.plans);
                app.UITable.Data = Data;
                app.UITable.ColumnName = Data.Properties.VariableNames;  
            else
                % Clear the table if no plans are found
                app.UITable.Data = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;

            %Data = [];
            %app.UITable.ColumnEditable = false(1, width(Data));
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
         
            Index = app.UITable.Selection;
            if isempty(Index) || (Index < 1)
                return
            end

            app.Pk = app.UITable.Data.pk(Index);
            app.Status = 'Open';
            uiresume(app.UIFigure);                        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.StartTimeEditField.Value = '';
            app.EndTimeEditField.Value = '';
            app.PlanTitleEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.OpenObservationPlanLabel = uilabel(app.Panel_2);
            app.OpenObservationPlanLabel.Text = 'Open Observation Plan';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Title'; 'User'; 'Updated'; 'Status'};
            app.UITable.RowName = {};
            app.FilterPanel = uipanel(app.UIFigure);
            app.FilterPanel.Title = 'Filter';
            app.ClearButton = uibutton(app.FilterPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StartTimeEditFieldLabel = uilabel(app.FilterPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.FilterPanel, 'text');
            app.StartTimeEditField.Editable = 'off';
            app.StartTimeEditField.Tooltip = {'Enter plan start time (i.e. 2024-12-04 00:00:00)'};
            app.StartTimeEditField.Placeholder = 'YYYY-MM-DD HH:MM:SS';
            app.EndTimeEditFieldLabel = uilabel(app.FilterPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.FilterPanel, 'text');
            app.EndTimeEditField.Editable = 'off';
            app.EndTimeEditField.Placeholder = 'YYYY-MM-DD HH:MM:SS';
            app.SearchButton = uibutton(app.FilterPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.PlanTitleEditFieldLabel = uilabel(app.FilterPanel);
            app.PlanTitleEditFieldLabel.Text = 'Plan Title';
            app.PlanTitleEditField = uieditfield(app.FilterPanel, 'text');
            app.PlanTitleEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end