classdef PlannerMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        DuplicateMenu                   matlab.ui.container.Menu
        CloseMenu_2                     matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        SaveToLocalFileMenu             matlab.ui.container.Menu
        OpenFromLocalFileMenu           matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        ConnectLoginMenu                matlab.ui.container.Menu
        DisconnectLogoutMenu            matlab.ui.container.Menu
        ExitPlannerMenu                 matlab.ui.container.Menu
        TargetsMenu                     matlab.ui.container.Menu
        AddUniqueTargetMenu             matlab.ui.container.Menu
        EditUniqueTargetMenu            matlab.ui.container.Menu
        DeleteUniqueTargetMenu          matlab.ui.container.Menu
        ClearAllUniqueTargetsMenu       matlab.ui.container.Menu
        ViewUniqueTargetsTableMenu      matlab.ui.container.Menu
        SaveUniqueTargetsToFileMenu     matlab.ui.container.Menu
        LoadUniqueTargetsFromFileMenu   matlab.ui.container.Menu
        PlanMenu_2                      matlab.ui.container.Menu
        EditPlanTargetMenu              matlab.ui.container.Menu
        DeletePlanTargetMenu            matlab.ui.container.Menu
        ClearAllPlanTargetsMenu         matlab.ui.container.Menu
        ViewPlanTableMenu               matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        ParametersMenu                  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        BuildMenu                       matlab.ui.container.Menu
        ValidateMenu                    matlab.ui.container.Menu
        SubmitMenu                      matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        ViewSkyMapPlotWindowMenu        matlab.ui.container.Menu
        ViewGraphsPlotWindowMenu        matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        LogWindowMenu                   matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        SNRCalculatorMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ObservationPlannerHelpMenu      matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup2                       matlab.ui.container.TabGroup
        StatusTab_2                     matlab.ui.container.Tab
        SubmitStatusButton              matlab.ui.control.Button
        ValidationStatusButton          matlab.ui.control.Button
        BuildStatusButton               matlab.ui.control.Button
        SubmitShortStatusEditField      matlab.ui.control.EditField
        ValidationShortStatusEditField  matlab.ui.control.EditField
        BuildShortStatusEditField       matlab.ui.control.EditField
        SubmitTimeEditField             matlab.ui.control.EditField
        SubmitEditFieldLabel            matlab.ui.control.Label
        ValidationTimeEditField         matlab.ui.control.EditField
        ValidationEditFieldLabel        matlab.ui.control.Label
        BuildTimeEditField              matlab.ui.control.EditField
        BuildEditFieldLabel             matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        RefreshApprovedTargetsButton_2  matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PlanParamsTab                   matlab.ui.container.Tab
        EndTimeEditField                matlab.ui.control.EditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        PlanTitleEditField              matlab.ui.control.EditField
        PlanTitleEditFieldLabel         matlab.ui.control.Label
        UserNameEditField               matlab.ui.control.EditField
        UserNameEditFieldLabel          matlab.ui.control.Label
        PlanTypeEditField               matlab.ui.control.EditField
        PlanTypeEditFieldLabel          matlab.ui.control.Label
        PlotGraphsPanel                 matlab.ui.container.Panel
        UpdateSkyMapButton_2            matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CalibrationStarButton           matlab.ui.control.RadioButton
        VisibilityButton                matlab.ui.control.RadioButton
        GraphPlotUniqueTargetDropDown   matlab.ui.control.DropDown
        cooSysLabel_3                   matlab.ui.control.Label
        PlotCalibObjDropDown            matlab.ui.control.DropDown
        cooSysLabel_2                   matlab.ui.control.Label
        AxesGraphsPlot                  matlab.ui.control.UIAxes
        PlotSkyMapPanel                 matlab.ui.container.Panel
        Panel_10                        matlab.ui.container.Panel
        PlotCooSysDropDown              matlab.ui.control.DropDown
        cooSysLabel_4                   matlab.ui.control.Label
        UpdateSkyMapButton              matlab.ui.control.Button
        PlotFlagVisibleCheckBox         matlab.ui.control.CheckBox
        PlotFlagApprovedCheckBox        matlab.ui.control.CheckBox
        PlotFlagCalibrationCheckBox     matlab.ui.control.CheckBox
        PlotFlagExtinctionCheckBox      matlab.ui.control.CheckBox
        PlotFlagPlanCheckBox            matlab.ui.control.CheckBox
        PlotFlagUniqueCheckBox          matlab.ui.control.CheckBox
        AxesSkymapPlot                  matlab.ui.control.UIAxes
        ApprovedTargetsPanel            matlab.ui.container.Panel
        Panel_5                         matlab.ui.container.Panel
        RefreshApprovedTargetsButton_3  matlab.ui.control.Button
        RefreshApprovedTargetsButton    matlab.ui.control.Button
        UITableApprovedTargets          matlab.ui.control.Table
        PlanPanel                       matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        EditPlanTargetButton_6          matlab.ui.control.Button
        EditPlanTargetButton_3          matlab.ui.control.Button
        EditPlanTargetButton_2          matlab.ui.control.Button
        EditPlanTargetButton            matlab.ui.control.Button
        CheckPlanTargetsButton          matlab.ui.control.Button
        BuildButton                     matlab.ui.control.Button
        UITablePlanTargets              matlab.ui.control.Table
        UniqueTargetsPanel              matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        EditPlanTargetButton_5          matlab.ui.control.Button
        EditPlanTargetButton_4          matlab.ui.control.Button
        LoadUniqueTargetsButton         matlab.ui.control.Button
        EditUniqueTargetButton          matlab.ui.control.Button
        AddUniqueTargetButton           matlab.ui.control.Button
        UITableUniqueTargets            matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        RetractButton                   matlab.ui.control.Button
        DuplicateButton                 matlab.ui.control.Button
        ModifiedLabel                   matlab.ui.control.Label
        ConnectionStatusEditField       matlab.ui.control.EditField
        ConnectionStatusEditFieldLabel  matlab.ui.control.Label
        SNRCalcButton                   matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        ParamsButton                    matlab.ui.control.Button
        ValidateButton                  matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
    end

    
    % =====================================================================
    %                              Properties
    % =====================================================================
    
    properties (Access = private)
        % Data
        MainModule                  %
        LoggerApp                   %

        % =================================================================
        %   Forms (AppDesigner Apps in ultrasat.planner.gui) - sorted abc
        % =================================================================        
        
        AboutApp                                % About
        AddPlanTargetApp                        % Plan Target - Add
        AddUniqueTargetApp                      % Unique Target - Add
        AdjustGroupStartTimeApp                 %
        ApprovedTargetParamsApp                 %
        ApprovedTargetsApp                      %
        CalibObjTableApp                        %
        EnterStartTimeApp                       %


        LoginApp                                % Login
        NewPlanApp                              % Description
        OpenPlanApp                             % Plan - Open
        PlanParamsApp                           %        
        PlanTargetParamsApp                     %
        PlotSkyMapApp                           %
        PlotGraphsApp                           %        
        PreferencesApp                          %

        %
        BuildStatusApp                          %
        ValidationStatusApp                     %
        SubmitStatusApp                         %

        % -----------------------------------------------------------------
        % Load
        LoadFromFileApp                         % Load file from disk
        LoadFromTextFileApp                     % Load from text file
        LoadPlanFromFileApp                     % Load file from disk
        LoadUniqueTargetsFromTextFileApp        % Load from text file

        % Save
        SavePlanToFileApp                       % Save to file
        SaveToFileApp                           % Save to file
        SaveToTextFileApp                       % Save to text file
        SaveUniqueTargetsToTextFileApp          % Save to text file

        % -----------------------------------------------------------------

        SettingsApp                             % Settings
        SubmitApp                               % Submit plan
        UniqueTargetParamsApp                   % Unique Target - Params
        UniqueTargetsApp                        % Unique Targets - Window
        ValidateApp                             % Validate

        % =================================================================
        % Data
        ReadOnly                    % = ~AllowEdit
        AllowEdit                   % = ~ReadOnly        
        UniqueTargetCalibObj        % returned by Planner.showCalibObj()
        PleaseWait                  %
        Preferences                 % Refrence to app.MainModule.Preferences
    end

    % =====================================================================
    %                         Init, Login, Log
    % =====================================================================
    methods (Access = public)
        function init(app)
            % Called from startupFcn() on application startup

            % Create MainModule that holds all common data
            app.MainModule = ultrasat.planner.gui.MainModule();
            app.MainModule.MainApp = app;
            app.Preferences = app.MainModule.Preferences;

            % Create log window
            app.LoggerApp = ultrasat.planner.gui.Logger(app.MainModule);
            app.LoggerApp.UIFigure.Visible = 'off';
            app.MainModule.LoggerApp = app.LoggerApp;
            app.msglog('init started');

            % @Todo - Setup UserName, need to be used with Connect/Login window
            % app.MainModule.UserName = 'YS';

            app.setButtons();

            app.msglog('init done');
        end

        % =================================================================
        %                              User Login
        % =================================================================
        function login(app)
            % User login
            app.msglog('login');
            if isempty(app.LoginApp) || ~isvalid(app.LoginApp)
                app.LoginApp = ultrasat.planner.gui.Login(app.MainModule);
            end            
            LoginStatus = app.showModal(app.LoginApp);
            app.msglog( sprintf('login uiwait returned: isempty: %d, isvalid: %d', isempty(app.LoginApp), isvalid(app.LoginApp)) );

            % @Todo
            if strcmp(LoginStatus, 'LoginOk')
                app.ConnectButton.Text = 'Connected';
                app.ConnectButton.BackgroundColor = [0.00, 1.00, 0.00];  % Green
            else
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [1.00, 1.00, 0.07];  % Yellow
            end
            app.setButtons();
        end

        function logout(app)
            % User logout
            app.msglog('logout');
            if strcmp(AppUtils.askYesNo(app, 'Are you sure you want to logout?', 'Confirm'), 'Yes')
                app.MainModule.logout();
            end
            app.setButtons();
        end        

        function Result = isLogin(app, Args)
            arguments
                app
                Args.Message = false
            end
            % Return true if used is logged-in
            Result = ~isempty(app.MainModule.UserName);
            if ~Result
                app.msglog('isLogin: not loggedin');
                if Args.Message
                    uialert(app.UIFigure, 'Login to the server is required to proceed with this operation', 'Message', 'Icon', 'success');            
                end
            end
        end

        function Result = isAllowed(app, Action)
            % Return true if specified action is allowed for current logged-in user
            Result = true;
            if ~Result
                app.msglog(sprintf('isAllowed: not allowed: %', Action));
            end            
        end 


        function setButtons(app)
            app.OpenButton.Enable = app.isLogin();
            app.SaveButton.Enable = app.isLogin();
            app.DuplicateButton.Enable = app.isLogin();            
            app.ValidateButton.Enable = app.isLogin();
            app.SubmitButton.Enable = app.isLogin();
        end


        function showLogger(app)
            % Show log window
            app.msglog('showLogger');
            if isempty(app.LoggerApp) || ~isvalid(app.LoggerApp)
                app.LoggerApp = ultrasat.planner.gui.Logger(app.MainModule);
            end            
            app.LoggerApp.UIFigure.Visible = 'on';
        end        


        function exitPlanner(app)
            if ~strcmp(AppUtils.askYesNo(app, 'Are you sure you want to exit the planner application?', 'Confirmation'), 'Yes')
                return;
            end            
        end
    end

    % =====================================================================
    %             Create New Plan - HCS, LCS, DDT, AllSS, ToO
    % =====================================================================    
    methods (Access = public)

        function createNewPlan(app)
            % Create new plan
            app.msglog('createNewPlan');            

            if app.MainModule.Modified
                if ~strcmp(AppUtils.askYesNo(app, 'Your changes are not saved. Do you want to discard them and create a new plan?', 'Save or discard'), 'Yes')
                    return;
                end
            end

            % Close existing plan
            app.closePlan();

            % Show form
            if isempty(app.NewPlanApp) || ~isvalid(app.NewPlanApp)
                app.NewPlanApp = ultrasat.planner.gui.NewPlan(app.MainModule);                
            end
            if strcmp(app.showModal(app.NewPlanApp), 'Create')
                app.msglog(sprintf('New plan type: %s ....', app.MainModule.PlanType));
                try
                    app.doCreateNewPlan();
                catch ME
                    app.msgex('createNewPlan', ME);
                end
            end
        end


        function doCreateNewPlan(app)
            % Create new plan according to parameters in app.NewPlanApp
            PlanType = app.NewPlanApp.PlanType;
            app.msglog(sprintf('doCreateNewPlan: PlanType: %s', PlanType));
            
            %
            app.MainModule.createPlanData();

            if strcmp(PlanType, 'HCS')
                app.doCreateNewPlanHCS();
            end
            if strcmp(PlanType, 'LCS')
                app.doCreateNewPlanLCS();
            end
            if strcmp(PlanType, 'DDT')
                app.doCreateNewPlanDDT();                
            end
            if strcmp(PlanType, 'AllSS')
                app.doCreateNewPlanAllSS();
            end
            if strcmp(PlanType, 'TOO')
                app.doCreateNewPlanTOO();
            end

            %
            app.MainModule.PlanData.planner = app.MainModule.Planner;
            app.MainModule.AfterBuild = false;
            app.SaveButton.Enable = 'off';

            if strcmp(PlanType, 'DDT')            
                app.BuildButton.Text = 'Add';
            else
                app.BuildButton.Text = 'Build';
            end

            app.setModified();
            app.showUniqueTargets();
            app.showPlanTargets();
            app.showCheckTimes();
            app.setStatus('OK', 'New plan created successfully');
            app.msglog('doCreateNewPlan done');
        end


        function doCreateNewPlanHCS(app)
            % Create new plan according to parameters in app.NewPlanApp
            app.msglog('doCreateNewPlanHCS started');
            UserName = app.MainModule.UserName;
            PlanTitle = app.MainModule.getFieldTitle('New plan');
            StartTime = app.MainModule.getFieldDateTime( app.NewPlanApp.StartTimeEditField.Value );
            EndTime = app.MainModule.getFieldDateTime( app.NewPlanApp.EndTimeEditField.Value );
            %EpochsPerVisit = app.MainModule.getFieldNum( app.NewPlanApp.EpochsPerVisitEditField.Value ); 
            %Exposure = app.MainModule.getFieldDuration( app.NewPlanApp.ExposureEditField.Value );

            %
            %HCS_fields = table({'S1','N2','N3'}',[67,215,254]',[-59,60,64]','VariableNames',{'Name','RA','Dec'},'RowNames',{'S1','N2','N3'}');

            upHCS = ultrasat.planner.uplanner('AstPlanner', UserName, 'Type', 'HCS', 'BaseDataDir', app.MainModule.BaseDataDir);
            upHCS.Title = PlanTitle;
            upHCS.StartTime = StartTime;
            upHCS.EndTime = EndTime;

            app.MainModule.setPlanner(upHCS);
            app.setModified();
            app.updatePlanParams();
            app.debugSave('upHCS.mat', app.MainModule.Planner);
            app.msglog('doCreateNewPlanHCS done');
        end


        function doCreateNewPlanLCS(app)
            % Create new plan according to parameters in app.NewPlanApp
            app.msglog('doCreateNewPlanLCS started');
            UserName = app.MainModule.UserName;
            PlanTitle = app.MainModule.getFieldTitle('New plan');
            StartTime = app.MainModule.getFieldDateTime( app.NewPlanApp.StartTimeEditField.Value );
            EndTime = app.MainModule.getFieldDateTime( app.NewPlanApp.EndTimeEditField.Value );

            upLCS = ultrasat.planner.uplanner('AstPlanner', UserName, 'Type', 'LCS', 'BaseDataDir', app.MainModule.BaseDataDir);
            upLCS.Title = PlanTitle;
            upLCS.StartTime = StartTime;
            upLCS.EndTime = EndTime;

            app.MainModule.setPlanner(upLCS);
            app.setModified();
            app.updatePlanParams();
            app.debugSave('upLCS.mat', app.MainModule.Planner);
            app.msglog('doCreateNewPlanLCS done');
        end


        function doCreateNewPlanDDT(app)
            % Create new plan according to parameters in app.NewPlanApp
            app.msglog('doCreateNewPlanDDT started');
            UserName = app.MainModule.UserName;
            PlanTitle = app.MainModule.getFieldTitle('New plan');
            StartTime = app.MainModule.getFieldDateTime( app.NewPlanApp.StartTimeEditField.Value );
            EndTime = app.MainModule.getFieldDateTime( app.NewPlanApp.EndTimeEditField.Value );

            upDDT = ultrasat.planner.uplanner('AstPlanner', UserName, 'Type', 'DDT', 'BaseDataDir', app.MainModule.BaseDataDir);

            app.MainModule.setPlanner(upDDT);
            app.setModified();
            app.updatePlanParams();
            app.debugSave('upDDT.mat', 'app.MainModule.Planner');
            app.msglog('doCreateNewPlanDDT done');
        end


        function doCreateNewPlanTOO(app)
            % Create new plan according to parameters in app.NewPlanApp
            app.msglog('doCreateNewPlanTOO started');
            UserName = app.MainModule.UserName;
            PlanTitle = app.MainModule.getFieldTitle('New plan');
            StartTime = app.MainModule.getFieldDateTime( app.NewPlanApp.StartTimeEditField.Value );
            EndTime = app.MainModule.getFieldDateTime( app.NewPlanApp.EndTimeEditField.Value );

            upTOO = ultrasat.planner.uplanner('AstPlanner', UserName, 'Type', 'TOO', 'BaseDataDir', app.MainModule.BaseDataDir);

            app.MainModule.setPlanner(upTOO);
            app.setModified();
            app.updatePlanParams();
            app.debugSave('upTOO.mat', 'app.MainModule.Planner');
            app.msglog('doCreateNewPlanTOO done');
        end


        function doCreateNewPlanAllSS(app)
            % Create new plan according to parameters in app.NewPlanApp
            app.msglog('doCreateNewPlanAllSS started');
            UserName = app.MainModule.UserName;
            PlanTitle = app.MainModule.getFieldTitle('New plan');
            StartTime = app.MainModule.getFieldDateTime( app.NewPlanApp.StartTimeEditField.Value );
            EndTime = app.MainModule.getFieldDateTime( app.NewPlanApp.EndTimeEditField.Value );

            upAllSS = ultrasat.planner.uplanner('AstPlanner', UserName, 'Type', 'AllSS', 'BaseDataDir', app.MainModule.BaseDataDir);

            app.MainModule.setPlanner(upAllSS);
            app.setModified();
            app.updatePlanParams();
            app.debugSave('upLCS.mat', 'app.MainModule.Planner');
            app.msglog('doCreateNewPlanAllSS done');
        end        
    end

    % =====================================================================
    %           Plan - Open, Save, Close, Load, Duplicate Plan
    % =====================================================================    
    methods (Access = public)

        function openPlan(app)
            % Load plan from database, requires login and server connection
            app.msglog('openPlan');                        
            if ~app.isLogin()
                %return;
            end

            if app.MainModule.Modified
                if ~strcmp(AppUtils.askYesNo(app, 'Your changes are not saved. Do you want to discard them and create a new plan?', 'Confirm'), 'Yes')
                    return;
                end
            end

            if isempty(app.OpenPlanApp) || ~isvalid(app.OpenPlanApp)
                app.OpenPlanApp = ultrasat.planner.gui.OpenPlan(app.MainModule);                
            end

            response = app.MainModule.ApiClient.getPlansList();
            if ~response.ok
                return;
            end

            app.OpenPlanApp.UITable.SelectionType = "row";
            app.OpenPlanApp.UITable.Multiselect = "off";            
            app.OpenPlanApp.UITable.RowName = "numbered";

            Data = struct2table(response.plans);
            
            app.OpenPlanApp.UITable.Data = Data;
            app.OpenPlanApp.UITable.ColumnName = Data.Properties.VariableNames;
            
            if strcmp(app.showModal(app.OpenPlanApp), 'Open')

                Pk = app.OpenPlanApp.Pk;
                response = app.MainModule.ApiClient.loadPlan(Pk);
                if response.ok
                    app.doOpenPlan(app.MainModule.ApiClient.PlanData);
                end

            end
            app.clearModified();
        end


        function doOpenPlan(app, PlanData)
            % called from openPlan()
            app.msglog(sprintf('doOpenPlan: %d', PlanData.pk));

            
        end
        

        function savePlan(app)
            % Save current plan to database, requires login and server connection
            app.msglog('savePlan');                        
            if ~app.isLogin()
                return;
            end
            if ~app.hasPlanner(), return; end

            app.showPleaseWait('Saving...');
            try
                app.MainModule.ApiClient.savePlan();
                app.clearModified();
            catch ME
                app.msgex('savePlan', ME);
            end

            app.closePleaseWait();            
            app.clearModified();
            app.MainModule.setStatus('OK', 'Plan saved successfully.');
        end


        function closePlan(app)
            app.msglog('closePlan');            
            if app.MainModule.Modified
                % Ask user to save current modified plan or to discard it
            end

            app.clearModified();
        end


        function deletePlan(app)
            app.msglog('deletePlan');
            if app.MainModule.Modified
                % Ask user to save current modified plan or to discard it
            end

            %app.clearModified();
        end


        function savePlanToFile(app)
            % Save current plan to text file, open dialog to ask user for
            % file name
            app.msglog('savePlanToFile');
            if ~app.hasPlanner(), return; end            

            if isempty(app.SavePlanToFileApp) || ~isvalid(app.SavePlanToFileApp)
                app.SavePlanToFileApp = ultrasat.planner.gui.SavePlanToFile(app.MainModule);                
                if ~isempty(app.Prefrences.LocalPlanFolder)
                    app.SavePlanToFileAppFileNameEditField.Value = app.Preferences.LocalPlanFileName;
                    app.SavePlanToFileApp.Folder = app.Preferences.LocalPlanFolder;
                end
            end
            if strcmp(app.showModal(app.SavePlanToFileApp), 'Save')
                app.showPleaseWait('Saving to file...');
                try
                    FileName = app.SavePlanToFileApp.FileName;
                    Planner = app.MainModule.Planner;
                    save(FileName, 'Planner');
                    app.setStatus('OK', sprintf('Plan saved to file: %s', FileName));

                    app.Preferences.LocalPlanFileName = FileName;
                    app.Preferences.LocalPlanFolder = fileparts(FileName);
                    app.savePreferences();
                catch ME
                    app.msgex('savePlanToFile', ME);
                end
                app.closePleaseWait();            
            end            
        end


        function loadPlanFromFile(app)
            % Load plan from file as matlab object
            app.msglog('loadPlanFromFile');

            if app.MainModule.Modified
                % @Todo - Save or discard
            end

            if isempty(app.LoadPlanFromFileApp) || ~isvalid(app.LoadPlanFromFileApp)
                app.LoadPlanFromFileApp = ultrasat.planner.gui.LoadPlanFromFile(app.MainModule);
                if ~isempty(app.Preferences.LocalPlanFolder)
                    app.LoadPlanFromFileApp.Value = app.Preferences.LocalPlanFileName;
                    app.LoadPlanFromFileApp.Folder = app.Preferences.LocalPlanFolder;
                end
            end
            if strcmp(app.showModal(app.LoadPlanFromFileApp), 'Load')
                try
                    FileName = app.LoadPlanFromFileApp.FileName;
                    Data = load(FileName);
                    app.MainModule.setPlanner(Data.Planner);
                    app.showPlanAll();
                    app.setStatus('OK', sprintf('Plan loaded from file: %s', FileName));

                    app.Preferences.LocalPlanFileName = FileName;
                    app.Preferences.LocalPlanFolder = fileparts(FileName);
                    app.savePreferences();                    
                catch ME
                    app.msgex('loadPlanFromFile', ME);
                end
            end                        
        end


        function duplicatePlan(app)
            %
            app.msglog('duplicatePlan');
            if ~app.hasPlanner(), return; end

        end


        function showPlanAll(app)
            app.showUniqueTargets();
            app.showPlanTargets();
            app.showApprovedTargets();            
        end
    end

    % =====================================================================    
    %                            Unique Targets    
    % =====================================================================
    methods (Access = public)
  
        function addUniqueTarget(app)
            app.msglog('addUniqueTarget');
            if ~app.hasPlanner(), return; end            

            % Show form
            if isempty(app.AddUniqueTargetApp) || ~isvalid(app.AddUniqueTargetApp)
                app.AddUniqueTargetApp = ultrasat.planner.gui.AddUniqueTarget(app.MainModule);                
            end
            if strcmp(app.showModal(app.AddUniqueTargetApp), 'Add')
                try
                    % Get field values
                    Name = app.MainModule.getFieldUniqueTargetName( app.AddUniqueTargetApp.NameEditField.Value );
                    RA = app.MainModule.getFieldRA( app.AddUniqueTargetApp.RAEditField.Value );
                    Dec = app.MainModule.getFieldDec( app.AddUniqueTargetApp.DecEditField.Value );
    
                    % Add to Planner
                    app.MainModule.Planner.addUniqTargets(RA, Dec, 'Name', Name);
                    app.setModified();

                    % Refresh table
                    app.showUniqueTargets();
                catch ME
                    app.msgex('addUniqueTarget', ME);
                end
            end
        end


        function editUniqueTarget(app)
            app.msglog('editUniqueTarget');
            if ~app.hasPlanner(), return; end

            % Get index of selected item
            Index = app.UITableUniqueTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end

            if isempty(app.UniqueTargetParamsApp) || ~isvalid(app.UniqueTargetParamsApp)
                app.UniqueTargetParamsApp = ultrasat.planner.gui.UniqueTargetParams(app.MainModule);                
            end
            
            % Set field values - Currently there are 9 fields for Unique Target
            ParamsApp = app.UniqueTargetParamsApp;
            UniqTarg = app.MainModule.Planner.UniqTarg;
            app.setUniqueTargetParamsFields(UniqTarg, Index, ParamsApp);

            % Show the form, update values if closed with Save
            if strcmp(app.showModal(app.UniqueTargetParamsApp), 'Save')
                Name = app.MainModule.getFieldUniqueTargetName( ParamsApp.NameEditField.Value );
                RA = app.MainModule.getFieldRA( ParamsApp.RAEditField.Value );
                Dec = app.MainModule.getFieldDec( ParamsApp.DecEditField.Value );
                app.setModified();
                try
                    app.MainModule.Planner.editUniqTarg(Index, 'Name', Name, 'RA', RA, 'Dec', Dec);
                    if app.checkPlan()
                        app.msglog('editUniqueTarget successfully');
                    end
                catch ME
                    app.msgex('editUniqTarget', ME);
                end
                app.showPlanAll();
            end            
        end


        function setUniqueTargetParamsFields(app, UniqTarg, Index, ParamsApp)           
            % Set field values - Currently there are 9 fields for Unique Target
            ParamsApp.UniqueTargetIndexEditField.Value = int2str(Index);
            ParamsApp.NameEditField.Value = UniqTarg.Name(Index);
            ParamsApp.RAEditField.Value = app.MainModule.ra2Str( UniqTarg.RA(Index) );
            ParamsApp.DecEditField.Value = app.MainModule.dec2Str( UniqTarg.Dec(Index) );
            ParamsApp.A_UEditField.Value = app.MainModule.num2Str( UniqTarg.A_U(Index) );
            ParamsApp.CalObjEditField.Value = app.MainModule.length2Str( UniqTarg.CalObj(Index) );
            ParamsApp.RefImagesIDsEditField.Value = app.MainModule.length2Str( UniqTarg.RefImageIDs(Index) );
            ParamsApp.ExtSurveysEditField.Value = app.MainModule.length2Str( UniqTarg.ExtSurveys(Index) );
            ParamsApp.FieldObjEditField.Value = app.MainModule.length2Str( UniqTarg.FieldObj(Index) );
            ParamsApp.HealpixArrayEditField.Value = app.MainModule.length2Str( UniqTarg.HealpixArray(Index) );
        end        


        function deleteUniqueTarget(app)
            app.msglog('deleteUniqueTarget');
            if ~app.hasPlanner(), return; end

            Index = app.UITableUniqueTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end

            Name = app.MainModule.Planner.UniqTarg(Index);
            if ~strcmp(AppUtils.askYesNo(app, sprintf('Delete selected unique target (%s)?', Name)), 'Yes')
                return;
            end

            app.setModified();
            try
                app.MainModule.Planner.delUniqTarg(Index, 'abort_if_in_plan', true);
            catch ME
                app.msgex('delUniqTarg', ME);
                if ~strcmp(AppUtils.askYesNo(app, sprintf('Unique target is used, deleting it will delete plan targets. Are you sure (%s)?', Name), 'Confirm'), 'Yes')
                    return;
                end                
            end

            try
                app.MainModule.Planner.delUniqTarg(Index, 'abort_if_in_plan', false);
            catch ME
                app.msgex('delUniqTarg', ME);
            end
            app.showPlanAll();
        end


        function loadUniqueTargetsFromFile(app)
            % Load unique targets list from text file (csv). Open dialog to
            % ask user for file name or paste the text.
            app.msglog('loadUniqueTargetsFromFile');
            if ~app.hasPlanner(), return; end

            % Show form
            if isempty(app.LoadUniqueTargetsFromTextFileApp) || ~isvalid(app.LoadUniqueTargetsFromTextFileApp)
                app.LoadUniqueTargetsFromTextFileApp = ultrasat.planner.gui.LoadUniqueTargetsFromTextFile(app.MainModule);                
                if ~isempty(app.PreferencesUniqueTargetsFile)
                    app.LoadUniqueTargetsFromTextFileApp.FileNameEditField.Value = app.Preferences.UniqueTargetsFile;
                    app.LoadUniqueTargetsFromTextFileApp.Folder = pp.Preferences.UniqueTargetsFolder;
                end
            end

            if strcmp(app.showModal(app.LoadUniqueTargetsFromTextFileApp), 'Load')

                app.showPleaseWait('Loading unique targets...');

                try
                    % Get field values
                    % FileName = app.LoadUniqueTargetsFromTextFileApp.FileName;

                    % Write text to tempfile
                    Text = app.LoadUniqueTargetsFromTextFileApp.Text;
                    FileName = tempname;
                    fid = fopen(FileName, 'w');
                    fwrite(fid, Text);
                    fclose(fid);

                    % Load data from tempfile
                    Data = [];                    
                    if ~isempty(FileName) && isfile(FileName)
                        % readtable expects uniform data types within each column, and it might misinterpret the file structure.
                        Data = readtable(FileName);
                    elseif ~isempty(Text)
                        Data = app.MainModule.loadTableFromCsvText(FileName);
                    end

                    if ~isempty(Data)
                        app.MainModule.Planner.addUniqTargets(Data.RA, Data.Dec, 'Name', Data.Name);
                        app.setModified();
                        app.showUniqueTargets();
                        app.setStatus('OK', 'Unique targets loaded successfully');

                        app.Preferences.UniqueTargetsFileName = app.LoadUniqueTargetsFromTextFileApp.FileNameEditField.Value;
                        app.Preferences.UniqueTargetsFolder = fileparts(app.LoadUniqueTargetsFromTextFileApp.FileNameEditField.Value);
                        app.savePreferences();
                    end
                catch ME
                    app.msgex('loadUniqueTargetsFromFile', ME);
                end

                app.closePleaseWait();
            end
        end


        function app.savePreferences(app)
            %
            app.Preferences.save();
        end


        function saveUniqueTargetsToFile(app)
            % Save unique targets list to text file (csv). Open dialog to
            % ask user for file name or paste the text.
            app.msglog('saveUniqueTargetsToFile');

            % Show form
            if isempty(app.SaveUniqueTargetsToTextFileApp) || ~isvalid(app.SaveUniqueTargetsToTextFileApp)
                app.SaveUniqueTargetsToTextFileApp = ultrasat.planner.gui.SaveUniqueTargetsToTextFile(app.MainModule);                
            end

            % Save to temp file and load as text, display in the dialog
            try
                TempFile = tempname;
                app.MainModule.Planner.saveUniqTargCooList(TempFile);
                Text = fileread(TempFile);
                app.SaveUniqueTargetsToTextFileApp.setText(Text);
            catch ME
                app.msgex('saveUniqTargCooList', ME);
            end                

            if strcmp(app.showModal(app.SaveUniqueTargetsToTextFileApp), 'Save')
                try
                    % Get field values
                    FileName = app.SaveUniqueTargetsToTextFileApp.FileName;
                    if ~isempty(FileName)
                        app.MainModule.Planner.saveUniqTargCooList(FileName);
                    end
                catch ME
                    app.msgex('saveUniqueTargetsToFile', ME);
                end
            end
        end


        function clearUniqueTargets(app)
            %
            if ~strcmp(AppUtils.askYesNo(app, 'Are you sure you want to delete ALL UNIQUE TARGETS ???', 'Delete all unique targets'), 'Yes')
                return;
            end

            try
                app.MainModule.Planner.clearUniqueTargets();
                app.showUniqueTargets();
            catch ME
                app.msgex('clearUniqueTargets', ME)
            end           
            app.showPlanAll();           
        end


        function uniqueTargetSelected(app, Index)
            Data = app.getSelectedTableRowAsStruct(app.MainModule.Planner.UniqTarg, Index);
            if ~isempty(Data)
                app.msglog(sprintf('uniqueTargetSelected: %d - %s', Index, Data.Name));
            end
        end


        function showUniqueTargets(app)
            % Update the display of Unique Targets table
            app.msglog('showUniqueTargets');
            if ~app.hasPlanner(), return; end

            app.UITableUniqueTargets.SelectionType = "row";
            app.UITableUniqueTargets.Multiselect = "off";
            app.UITableUniqueTargets.RowName = "numbered";

            % Add 'Order' column
            Data = app.MainModule.Planner.UniqTarg;
            Data = addvars(Data, repmat("", height(Data), 1), 'Before', 1, 'NewVariableNames', 'Order');
            %Data = addvars(Data, false(height(Data), 1), 'Before', 1, 'NewVariableNames', 'Checked');

            % Make only the first column editable, others non-editable
            nColumns = width(Data); 
            editableArray = false(1, nColumns); 
            editableArray(1) = true; 
            app.UITableUniqueTargets.ColumnEditable = editableArray;

            % Apply style to the entire 'Order' column (first column)
            s = uistyle("BackgroundColor",[1 0.85 0.4]); % Light orange color
            addStyle(app.UITableUniqueTargets, s, "column", 1);

            app.UITableUniqueTargets.Data = Data;
            app.UITableUniqueTargets.ColumnName = Data.Properties.VariableNames; 

            % Extract unique values from the 'obj' column of the table
            Values = unique(app.MainModule.Planner.UniqTarg.Name, 'stable');
            if isempty(Values)
                app.GraphPlotUniqueTargetDropDown.Items = {};
                app.GraphPlotUniqueTargetDropDown.Value = {};
            else
                % Set the dropdown items to these values
                SaveValue = app.GraphPlotUniqueTargetDropDown.Value;
                app.GraphPlotUniqueTargetDropDown.Items = string(Values);

                if ~isempty(SaveValue) && any(ismember(app.MainModule.Planner.UniqTarg.Name, SaveValue))
                    app.GraphPlotUniqueTargetDropDown.Value = SaveValue;
                else
                    app.GraphPlotUniqueTargetDropDown.Value = Values{1};
                end            
            end
        end


        function showUniqueTargetsWindow(app)
            %
            app.msglog('showUniqueTargetsWindow');
            if ~app.hasPlanner(), return; end

            if isempty(app.UniqueTargetsApp) || ~isvalid(app.UniqueTargetsApp)
                app.UniqueTargetsApp = ultrasat.planner.gui.UniqueTargets();
                app.UniqueTargetsApp.MainApp = app;
            end
            app.UniqueTargetsApp.UIFigure.Visible = 'on';
        end        


        function uniqueTargetClick(app)
            Index = app.UITableUniqueTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end            
        end


        function uniqueTargetDoubleClick(app)
            Index = app.UITableUniqueTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end            
        end        

    end

    % =====================================================================
    %                            Plan Targets
    % =====================================================================
    methods (Access = public)

        function addPlanTarget(app)
            % Add the currently selected Unique Target to Plan Targets
            % @Todo - Check with Yossi if we need it or only by Build
            app.msglog('addPlanTarget');

        end        


        function editPlanTarget(app)
            app.msglog('editPlanTarget');
            if ~app.hasPlanner(), return; end

            Index = app.UITablePlanTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end

            if isempty(app.PlanTargetParamsApp) || ~isvalid(app.PlanTargetParamsApp)
                app.PlanTargetParamsApp = ultrasat.planner.gui.PlanTargetParams(app.MainModule);                
            end

            % Set field values - Currently there are 23 fields for Plan Target
            ParamsApp = app.PlanTargetParamsApp;
            Plan = app.MainModule.Planner.Plan;
            app.setPlanTargetParamsFiels(Plan, Index, ParamsApp);          
            ParamsApp.setEditMode(false);

            if strcmp(app.showModal(app.PlanTargetParamsApp), 'Save')
                try
                    ExpTime = seconds(ParamsApp.ExposureTimeEditField.Value);
                    Nexposures = ParamsApp.EpochsPerVisitEditField.Value;
                    Tiles = app.MainModule.getTilesFromCheckboxes(ParamsApp);
                    %Tiles = ParamsApp.TilesEditField.Value;

                    if ExpTime == Plan.ExpTime(Index)
                        ExpTime = [];
                    end

                    if Nexposures == Plan.Nexposures(Index)
                        Nexposures = [];
                    end

                    if strcmp(Tiles, Plan.Tiles(Index))
                        Tiles = [];
                    end

                    %
                    app.MainModule.Planner.editPlanRow(Index, 'ExpTime', ExpTime, 'Tiles', Tiles, 'Nexposures', Nexposures);  % , 'updateRowsProp', true);

                    if app.checkPlan()
                        app.msglog('editPlanRow successfully');
                    end                    
                catch ME
                    app.msgex('editPlanRow', ME);
                end

                app.showPlanAll();
                app.setModified();
            end            

            % Todo - Yossi - need to reflect changes in all plan targets,
            % rebulid etc.
        end
 

        function setPlanTargetParamsFiels(app, Plan, Index, ParamsApp)
            % Set field values - Currently there are 23 fields for Plan Target
            
            app.PlanTargetIndexEditField.Value = int2str(Index);

            % Editable fields
            ParamsApp.ExposureTimeEditField.Value = seconds(Plan.ExpTime(Index));  % Numeric field
            ParamsApp.EpochsPerVisitEditField.Value = Plan.Nexposures(Index);
            app.MainModule.updateCheckboxesFromTiles(ParamsApp, Plan.Tiles(Index));
            %ParamsApp.TilesEditField.Value = Plan.Tiles(Index);  % String field

            % String fields
            ParamsApp.NameEditField.Value = Plan.Name(Index);
            
            % Integer fields (uint8 → convert to string)
            ParamsApp.UniqueTargetIndexEditField.Value = num2str(Plan.UniqTargInd(Index));
            ParamsApp.GroupEditField.Value = num2str(Plan.Group(Index));
             
            % Double fields (convert to string for display)
            ParamsApp.RAEditField.Value = app.MainModule.ra2Str( Plan.RA(Index) );
            ParamsApp.DecEditField.Value = app.MainModule.dec2Str( Plan.Dec(Index));
            ParamsApp.ExpectedRollEditField.Value = num2str(Plan.ExpectedRoll(Index));
            
            % Datetime fields (convert to string using date format)
            ParamsApp.StartTimeEditField.Value = app.MainModule.DateTime2Str(Plan.Tstart(Index));
            ParamsApp.EndTimeEditField.Value = app.MainModule.DateTime2Str(Plan.Tend(Index));
             
            % Double fields (convert to string)
            ParamsApp.MJDstartEditField.Value = num2str(Plan.JDstart(Index));
            ParamsApp.MJDendEditField.Value = num2str(Plan.JDend(Index));
             
            % Duration fields (convert to string)            
            ParamsApp.TotalDurationEditField.Value = char(Plan.TotalDuration(Index));
            ParamsApp.SlewTimeBeforeEditField.Value = char(Plan.SlewTimeBefore(Index));
                       
            % Logical fields (convert to "Yes" / "No" or "1"/"0")
            ParamsApp.NoCommEditField.Value = string(Plan.NoComm(Index)); % "true"/"false"
            ParamsApp.HardObsEditField.Value = string(Plan.HardObs(Index));
            
            % Double fields (convert to string)
            ParamsApp.MoonDistEditField.Value = num2str(Plan.MoonDist(Index));
            ParamsApp.SunDistEditField.Value = num2str(Plan.SunDist(Index));
            ParamsApp.EarthDistEditField.Value = num2str(Plan.EarthDist(Index));
            ParamsApp.ZodyEditField.Value = num2str(Plan.Zody(Index));
            ParamsApp.LimMagEditField.Value = num2str(Plan.LimMag(Index));
            
            % Cell array field (convert to comma-separated string for display)
            ParamsApp.OverlapTargetsEditField.Value = app.MainModule.cell2Str(Plan.OverlapTargets);
        end


        function deletePlanTarget(app)
            % Delete plan target by calling delPlanRow()
            app.msglog('deletePlanTarget');
            if ~app.hasPlanner(), return; end
    
            Index = app.UITablePlanTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end

            Name = sprintf('%d', Index);  % app.MainModule.Planner.UniqTarg(Index);
            if ~strcmp(AppUtils.askYesNo(app, sprintf('Delete selected target (%s)?', Name), 'Confirm'), 'Yes')
                return;
            end

            app.setModified();
            try
                app.MainModule.Planner.delPlanRow(Index);
            catch ME
                app.msgex('delPlanRow', ME);
                %if ~strcmp(AppUtils.askYesNo(app, sprintf('Unique target is used, deleting it will delete plan targets. Are you sure (%s)?', Name)), 'Yes')
                %    return;
                %end                
            end
            app.showPlanAll();
        end


        function clearPlanTargets(app)
            %
            app.msglog('clearPlanTargets');            
            if ~app.hasPlanner(), return; end

            if ~strcmp(AppUtils.askYesNo(app, 'Are you sure you want to delete ALL TARGETS ???', 'Delete all targets'), 'Yes')
                return;
            end

            try
                app.MainModule.Planner.clearPlan();                
            catch ME
                app.msgex('clearPlanTargets', ME)
            end                       
            app.showPlanAll();
        end


        function planTargetSelected(app, Index)
            % Called on
            if ~app.hasPlanner(), return; end
            Data = app.getSelectedTableRowAsStruct(app.MainModule.Planner.Plan, Index);
            if ~isempty(Data)
                app.msglog(sprintf('planTargetSelected: %d - %s', Index, Data.Name));
                app.showOverriddenApprovedTargets(Index);
            end
        end
        

        function showPlanTargets(app)
            % Update the display of Plan Targets table
            app.msglog('showPlanTargets');
            if ~app.hasPlanner(), return; end

            app.UITablePlanTargets.SelectionType = "row";
            app.UITablePlanTargets.Multiselect = "off";            
            app.UITablePlanTargets.RowName = "numbered";

            Data = app.MainModule.Planner.Plan;

            % Add checkbox, make only the first column editable, others non-editable
            AddCheck = false;
            if AddCheck
                Data = addvars(Data, false(height(Data), 1), 'Before', 1, 'NewVariableNames', 'Checked');
                editableArray = false(1, width(Data));
                editableArray(1) = true; 
            else
                editableArray = false(1, width(Data));                
            end
            app.UITableUniqueTargets.ColumnEditable = editableArray;            

            app.UITablePlanTargets.Data = Data;
            app.UITablePlanTargets.ColumnName = Data.Properties.VariableNames; 
        end


        function adjustGroupStartTime(app)
            % 
            app.msglog('adjustGroupStartTime');
            if ~app.hasPlanner(), return; end

            if isempty(app.AdjustGroupStartTimeApp) || ~isvalid(app.AdjustGroupStartTimeApp)
                app.AdjustGroupStartTimeApp = ultrasat.planner.gui.AdjustGroupStartTime(app.MainModule);                
            end

            % Prepare dialog
            %app.AdjustGroupStartTimeApp.GroupDropDown = ; 
            %app.AdjustGroupStartTimeApp.ShiftTimeEditField = ; 
            %app.AdjustGroupStartTimeApp.StartTimeEditField = ; 

            uniqueGroups = unique(app.MainModule.Planner.Plan.Group);
            groupItems = cellstr(string(uniqueGroups));
            groupItems = ['All'; groupItems];
            app.AdjustGroupStartTimeApp.GroupDropDown.Items = groupItems;

            if strcmp(app.showModal(app.AdjustGroupStartTimeApp), 'OK')
                try
                    GroupList = app.AdjustGroupStartTimeApp.GroupList;
                    if strcmp(app.AdjustGroupStartTimeApp, 'Relative')
                        app.MainModule.Planner.adjustGroupStartTime('GroupList', GroupList, 'ShiftTime', ShiftTime);                            
                    elseif strcmp(app.AdjustGroupStartTimeApp, 'Shift')
                        app.MainModule.Planner.adjustGroupStartTime('GroupList', GroupList, 'ShiftTime', app.AdjustGroupStartTimeApp.ShiftTime);
                    elseif strcmp(app.AdjustGroupStartTimeApp, 'StartTime')
                        app.MainModule.Planner.adjustGroupStartTime('GroupList', GroupList, 'NewStartTime', app.AdjustGroupStartTimeApp.StartTime);
                    end                   
                catch ME
                    app.msgex('adjustGroupStartTime', ME)
                end                       
            end
        end


        function planRowClick(app)
            Index = app.UITablePlanTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end            
        end
    
        function planRowDoubleClick(app)
            Index = app.UITablePlanTargets.Selection;
            if isempty(Index) || (Index < 1)
                return
            end                        
        end
                
    end


    % =====================================================================
    %                               Build
    % =====================================================================
    methods (Access = public)

        function build(app)
            % Build plan according to plan type, calls doBuild...() below
            app.msglog('build');            
            if ~app.hasPlanner(), return; end

            %
            if app.MainModule.AfterBuild
                if ~strcmp(AppUtils.askYesNo(app, 'Build was already executed, this will override you existing plan. Are you sure you want to execute build?', 'Confirm'), 'Yes')
                    return;
                end
            end

            app.showPleaseWait('Building your plan...');

            try
                PlanType = app.MainModule.PlanType;
                app.MainModule.clearStatus();
                app.updateStatus();
                app.msglog(sprintf('build: PlanType: %s', PlanType));

                if strcmp(PlanType, 'HCS')
                    app.doBuildHCS();
                end    
                if strcmp(PlanType, 'LCS')
                    app.doBuildLCS();
                end   
                if strcmp(PlanType, 'DDT')
                    app.doBuildDDT();
                end    
                if strcmp(PlanType, 'TOO')
                    app.doBuildTOO();
                    %app.MainModule.Planner.buildTOO();
                end
                if strcmp(PlanType, 'AllSS')
                    app.doBuildAllSS();
                    %app.MainModule.Planner.buildAllSS();
                end                    

                %
                if ~strcmp(PlanType, 'DDT')                
                    if ~isempty(app.MainModule.Planner.Plan)
                        app.MainModule.AfterBuild = true;
                    end
                end

            catch ME
                app.MainModule.setStatus('Error', ME.message);
                app.msgex('build', ME);
            end

            % Close the "Please Wait" dialog
            app.closePleaseWait();

            % Update display
            app.setModified();  % Move call to other place?
            app.updateStatus();
            app.showPlanTargets();                                
        end


        function doBuildHCS(app)
            % Build HCS            
            app.msglog('doBuildHCS started');
            if ~app.hasPlanner(), return; end

            upHCS = app.MainModule.Planner;
            upHCS.buildHCS();
            app.MainModule.SelfConsistencyStatus = 'OK';
            app.MainModule.BuildStatus = 'OK';
            app.MainModule.setStatus('OK', 'Build: self consistency: OK');
            app.debugSave('upHCS.mat', upHCS);

            app.msglog('doBuildHCS done');
        end


        function doBuildLCS(app)
            % Build LCS
            app.msglog('doBuildLCS started');
            if ~app.hasPlanner(), return; end

            upLCS = app.MainModule.Planner;

            % Set from default settings
            upLCS.StartTime = 'now';
            upLCS.EndTime = upLCS.StartTime+caldays(45);            
            upLCS.DailyWindowStartTime = duration('03:00:00');

            upLCS.updateTargetVisibility('WindowStartTime', upLCS.StartTime, 'WindowEndTime', upLCS.EndTime);

            %F2 = find( all(upLCS.Vis.SunLimits & upLCS.Vis.EarthLimits & upLCS.Vis.MoonLimits, 1) );

            % Fakely retrive upHCS ar approved target list
            % upLCS.retrieveMissionApprovedPlan('inputPlan', upLCS.Plan);
          
            % check with struct - @Todo - comment out
            % api_response = load('C:/AstroPack/data/ULTRASAT/api_response.mat');
            % upLCS.retrieveMissionApprovedPlan('inputPlan', api_response.response);
            % app.MainModule.Planner.retrieveMissionApprovedPlan('Mclient', app.MainModule.ApiClient);
          
            SelectedRows = app.getUniqueTargetsIndexByOrderColumn(app.UITableUniqueTargets.Data);

            upLCS.buildLCS('TargetList', SelectedRows);  %F2);

            % Check adjustments relative to Approved List
            upLCS.adjustGroupStartTime();  
          
            CheckStatus = upLCS.planSelfConsistencyCheck();

            %CheckStatusStr = {'Issues found', 'OK'};
            if CheckStatus
                app.MainModule.SelfConsistencyStatus = 'OK';
                app.MainModule.setStatus('OK', 'Build: self consistency: OK');
            else
                app.MainModule.SelfConsistencyStatus = 'Issues found';
                app.MainModule.setStatus('Error', 'Build: self consistency: issues found');
            end

            app.MainModule.BuildStatus = app.MainModule.SelfConsistencyStatus;
            app.debugSave('upLCS.mat', upLCS);

            app.msglog('doBuildLCS done');
        end


        function doBuildDDT(app)
            % Build DDT
            app.msglog('doBuildDDT started');
            if ~app.hasPlanner(), return; end

            upDDT = app.MainModule.Planner;
            
            SelectedRows = app.getUniqueTargetsIndexByOrderColumn(app.UITableUniqueTargets.Data);
            if isempty(SelectedRows)
                return;
            end

            if isempty(app.EnterStartTimeApp) || ~isvalid(app.EnterStartTimeApp)
                app.EnterStartTimeApp = ultrasat.planner.gui.EnterStartTime(app.MainModule);
            end            
            if strcmp(app.showModal(app.EnterStartTimeApp), 'OK')
                StartTime = app.EnterStartTimeApp.StartTime;
                app.msglog(sprintf('DT: %s ....', StartTime));
                try
                    Group = [];
                    if ~isempty(upDDT.Plan.Group)
                        Group = max(upDDT.Plan.Group) + 1;
                    end

                    upDDT.addDDT2Plan(SelectedRows, StartTime, 'Group', Group);
                    app.setStatus('OK', 'build: addDDT2Plan successfully');
                catch ME
                    app.msgex('addDDT2Plan', ME);
                end
            end

            
            %upDDT.addDDT2Plan([3,2],'2028-01-05 00:10:00'); 
            
            app.debugSave('upDDT.mat', upDDT);

            app.msglog('doBuildDDT done');
        end


        function doBuildTOO(app)
            % Build TOO - @Todo
            app.msglog('doBuildTOO started');
            if ~app.hasPlanner(), return; end

            upTOO = app.MainModule.Planner;

            Fields = upTOO.UniqTarg(1);
            upTOO.buildTOO('RA', Fields.RA, 'Dec', Fields.Dec, 'Name', HCS_fields.Name);

            app.debugSave('upTOO.mat', upTOO);

            app.msglog('doBuildTOO done');
        end


        function doBuildAllSS(app)
            % Build AllSS @Todo
            app.msglog('doBuildAllSS started');
            if ~app.hasPlanner(), return; end

            app.msglog('doBuildAllSS done');
        end        
        

        function showBuildStatusWindow(app)
            %
            app.msglog('showBuildStatusWindow');
            if ~app.hasPlanner(), return; end

            if isempty(app.BuildStatusApp) || ~isvalid(app.BuildStatusApp)
                app.BuildStatusApp = ultrasat.planner.gui.BuildStatus(app.MainModule);
            end
            app.BuildStatusApp.setData(app.MainModule.BuildStatus);
            app.showModal(app.BuildStatusApp);
        end


        function showValidationStatusWindow(app)
            %
            app.msglog('showValidationStatusWindow');
            if ~app.hasPlanner(), return; end

            if isempty(app.ValidationStatusApp) || ~isvalid(app.ValidationStatusApp)
                app.ValidationStatusApp = ultrasat.planner.gui.ValidationStatus(app.MainModule);
            end            
            app.ValidationStatusApp.setData(app.MainModule.ValidationStatus);
            app.showModal(app.ValidationStatusApp);
        end


        function showSubmitStatusWindow(app)
            %
            app.msglog('dshowSubmitStatusWindow');
            if ~app.hasPlanner(), return; end

            if isempty(app.SubmitStatusApp) || ~isvalid(app.SubmitStatusApp)
                app.SubmitStatusApp = ultrasat.planner.gui.SubmitStatus(app.MainModule);
            end            
            app.SubmitStatusApp.setData(app.MainModule.SubmitStatus);
            app.showModal(app.SubmitStatusApp);
        end        
    end
    
    % =====================================================================
    %                           Approved Targets
    % =====================================================================
    methods (Access = public)


        function retrieveApprovedTargets(app)
            %
            app.msglog('retrieveApprovedTargets');
            if ~app.hasPlanner(), return; end

            if app.MainModule.AfterBuild
                if ~strcmp(AppUtils.askYesNo(app, 'Retreiving approved targets after build may result in inconsistent plan. Are you sure you want to retreive mission approved targets?', 'Confirm'), 'Yes')
                    return;
                end
            end

            try
                app.MainModule.Planner.retrieveMissionApprovedPlan('Mclient', app.MainModule.ApiClient);
            catch ME
                app.msgex('retrieveApprovedTargets', ME);
            end

            app.showApprovedTargets();
        end


        function showApprovedTargets(app)
            % Update the display of Approved Targets table
            app.msglog('showApprovedTargets');
            if ~app.hasPlanner(), return; end

            app.UITableApprovedTargets.SelectionType = "row";
            app.UITableApprovedTargets.Multiselect = "off";            
            app.UITableApprovedTargets.RowName = "numbered";

            Data = app.MainModule.Planner.MissionApprovedPlan;
            
            app.UITableApprovedTargets.Data = Data;
            app.UITableApprovedTargets.ColumnName = Data.Properties.VariableNames;

            app.ApprovedTargetsPanel.Title = sprintf('Approved Targets: %s', datestr(now, 'yyyy-mm-dd HH:MM:SS'));  % app.MainModule.Planner.RetrivedMissionTime
        end


        function clearApprovedTargets(app)
            %
            app.msglog('clearApprovedTargets');
            if ~app.hasPlanner(), return; end

            app.MainModule.Planner.clearMissionApprovedPlan();
        end


        function approvedTargetSelected(app, Index)
            %
            Data = app.getSelectedTableRowAsStruct(app.MainModule.Planner.MissionApprovedPlan, Index);
            if ~isempty(Data)
                app.msglog(sprintf('approvedTargetSelected: %d - %s', Index, Data.Name));
            end
        end        


        function showOverriddenApprovedTargets(app, PlanTargetIndex)
            % Update the display with list of approved targets

            app.showApprovedTargets();
            PlanTarget = app.getSelectedTableRowAsStruct(app.MainModule.Planner.Plan, PlanTargetIndex);
            if isempty(PlanTarget)
                return;
            end

            Targets = PlanTarget.OverlapTargets;
            if ~isempty(Targets)

                % Mark the rows
                Style = uistyle("BackgroundColor",[1, 0.6, 0.6]);
                addStyle(app.UITableApprovedTarget, Style, "row", Targets);
            
                % Scroll to the row
                scroll(app.UITableApprovedTargets, "row", Targets(1));
            end
        end

    end

    % =====================================================================
    %                         Plan Params Window
    % =====================================================================
    methods (Access = public)
        
        function showPlanParamsWindow(app)
            % Show window with Plan Parameters
            app.msglog('showPlanParamsWindow');
            if ~app.hasPlanner(), return; end            

            Planner = app.MainModule.Planner;
            if isempty(app.PlanParamsApp) || ~isvalid(app.PlanParamsApp)
                app.PlanParamsApp = ultrasat.planner.gui.PlanParams(app.MainModule);
                app.PlanParamsApp.MainApp = app;
            end
            app.PlanParamsApp.UIFigure.Visible = 'on';

            ParamsApp = app.PlanParamsApp;
            ParamsApp.PlanTypeEditField.Value = Planner.Type;

            app.MainModule.AfterBuild = height(Planner.Plan);

            %
            if app.MainModule.AfterBuild
                ParamsApp.setReadOnly(true);
            else
                ParamsApp.setReadOnly(false);
            end

            if strcmp(app.showModal(ParamsApp), 'Save')
                % Call only if chnage - adjustCheckTimes(Obj,CheckStartTime,CheckEndTime)
            end
        end


        function setPlanParamsFields(app, ParamsApp)
            % Get the Planner instance from the main module
            Planner = app.MainModule.Planner;
        
            % Assign values to UI fields
            ParamsApp.TitleEditField.Value = Planner.Title;
            ParamsApp.PlanTypeDropDown.Value = Planner.Type;
            ParamsApp.StartTimeEditField.Value = app.MainModule.DateTime2Str(Planner.StartTime);
            ParamsApp.EndtimeEditField.Value = app.MainModule.DateTime2Str(Planner.EndTime);
            ParamsApp.ExposureEditField.Value = seconds(Planner.Exptime);
            ParamsApp.EpochsPerVisitEditField.Value = Planner.DefEpochsPerVisit;
            
            % Assign tile checkboxes
            tileNumbers = ["1", "2", "3", "4"];
            checkBoxes = [ParamsApp.Tile1CheckBox, ParamsApp.Tile2CheckBox, ParamsApp.Tile3CheckBox, ParamsApp.Tile4CheckBox];
            
            for i = 1:length(tileNumbers)
                checkBoxes(i).Value = ismember(tileNumbers(i), Planner.Tiles);
            end
            
            % Assign folders and files
            ParamsApp.BaseDataDirEditField.Value = Planner.BaseDataDir;
            ParamsApp.CalSubDirEditField.Value = Planner.CalibDir;
            ParamsApp.CalObjFlieEditField.Value = Planner.CalibObj;
        
            % Assign Unique Targets & Plan Targets
            ParamsApp.PlanTargetsEditField.Value = num2str(Planner.N_planTargets);
            ParamsApp.UniqueTargetsEditField.Value = num2str(Planner.N_uniqueTargets);
        
            % Assign Check Times
            ParamsApp.CheckStartTimeEditField.Value = Planner.CheckTimes(1);
            ParamsApp.CheckEndTimeEditField.Value = Planner.CheckTimes(2);
        
            % Assign System Parameters
            ParamsApp.FieldOfViewRadiusEditField.Value = Planner.Rfov;
            ParamsApp.TileReadTimeEditField.Value = seconds(Planner.FullTileReadTime);
            ParamsApp.SlewBufferEditField.Value = seconds(Planner.DefSlewBuffer);
        
            % Assign LCSTab Parameters
            ParamsApp.LcsDailyWindowStartTimeEditField.Value = app.MainModule.DateTime2Str(Planner.DailyWindowStartTime);
            ParamsApp.LcsDailyWindowMaxDurationEditField.Value = hours(Planner.DailyWindowMaxDuration);
        
            % Assign AllSkyTab Parameters
            ParamsApp.AllSkyDailyWindowStartTimeEditField.Value = app.MainModule.DateTime2Str(Planner.DailyWindowStartTime);
            ParamsApp.AllSkyDailyWindowMaxDurationEditField.Value = hours(Planner.DailyWindowMaxDuration);
            ParamsApp.AllSkyGalacticLatTresholdEditField.Value = Planner.AllSSHighLatThresh;
            ParamsApp.AllSkyLatVisitsEditField.Value = Planner.LowLatVisits;
            ParamsApp.AllSkyLowLatVisitsEditField.Value = Planner.HighLatVisits;
            ParamsApp.AllSkyHighGalacticLatDitherPatternDropDown.Value = Planner.DitherPattern;
        
            % Assign TOOTab Parameters
            ParamsApp.TooStartTimeEditField.Value = Planner.TOOStartTime;
            ParamsApp.TooWindowDurationEditField.Value = hours(Planner.TOOWindowDuration);
        
            % Assign Mission Status Fields
            ParamsApp.PlanStatusEditField.Value = Planner.Status;
            ParamsApp.AstPlannerEditField.Value = Planner.AstPlanner;
            ParamsApp.SubmitTimeEditField.Value = Planner.SubmittedTime;
            ParamsApp.ValidationTimeEditField.Value = Planner.ValidatedTime;
            ParamsApp.BuildTimeEditField.Value = Planner.ScheduledTime;
        
            % Assign Mission Distance Constraints
            ParamsApp.FieldOfViewRadiusEditField_2.Value = Planner.ObsSunDist;
            ParamsApp.FieldOfViewRadiusEditField_3.Value = Planner.ObsMoonDist;
            ParamsApp.FieldOfViewRadiusEditField_4.Value = Planner.ObsEarthDist;
            
            % Assign Plan Buttons
            ParamsApp.SaveButton.Enable = true;
            ParamsApp.CancelButton.Enable = true;
        end

        
    end

    % =====================================================================
    %                           Other Windows
    % =====================================================================

    methods (Access = public)    
        function showAboutWindow(app)
            % Show the About window
            if isempty(app.AboutApp) || ~isvalid(app.AboutApp)
                app.AboutApp = ultrasat.planner.gui.About(app.MainModule);                
            end
            uiwait(app.AboutApp.UIFigure);
        end


        function showSnrCalculator(app)
            % Open website in browser window, with out SNR Calculator
            web('https://snrapp.ultrasatsoc.org/');
        end

        % =================================================================        


    end

    % =====================================================================
    %                               Plots
    % =====================================================================    
    methods (Access = public)

        function showSkyMapPlot(app)
            app.msglog('showSkyMapPlot');
            if ~app.hasPlanner(), return; end            

            Planner = app.MainModule.Planner;

            % Get index of selected item - Currently unused - @Yossi
            % UniqueTargetIndex = app.UITableUniqueTargets.Selection;
            % if isempty(UniqueTargetIndex) || (UniqueTargetIndex < 1)
            %     return
            % end

            try

                Planner.plotMapPlan('AxesHandle', app.AxesSkymapPlot, 'disp_uniqTarg',true,'disp_plan',true,'ExtinctionMap',true,'CalObjMap',true,'disp_MissAprvPlan',true);
                return

                Planner.plotMapPlan('AxesHandle', app.AxesSkymapPlot, ...
                    'disp_uniqTarg', app.PlotFlagUniqueCheckBox.Value, ...
                    'disp_plan',  app.PlotFlagPlanCheckBox.Value, ...
                    'ExtinctionMap',   app.PlotFlagExtinctionCheckBox.Value, ...
                    'CalObjMap', app.PlotFlagCalibrationCheckBox.Value, ...
                    'disp_MissAprvPlan', app.PlotFlagApprovedCheckBox.Value, ...
                    'vis_at_time_map', app.PlotFlagVisibleCheckBox.Value);  % , ...
                    % 'cooSys', app.PlotCooSysDropDown.Value, ...
                    % 'plotTstart', app.MainModule.getFieldDateTime(app.PlotStartTimeEditField.Value), ...
                    % 'plotTend', app.MainModule.getFieldDateTime(app.PlotEndTimeEditField.Value) );
        
                    % Currently not implemented:
                    % In addition, there are 3 lists that can be set to select a subsample from 
                    % the full sample: 'UniqTargInds', 'plan_rows', 'MissAprvPlan_rows'.
                    % If they are empty (the default) will plot the entire UniqTarg list / Plan / MissionApprovedPlan.

            catch ME
                app.msgex('plotMapPlan', ME);
            end   
        end

        
        function showSkyMapPlotWindow(app)
            app.msglog('plotCalibObj');
            if ~app.hasPlanner(), return; end

            % @Todo
            if isempty(app.PlotSkyMapApp) || ~isvalid(app.PlotSkyMapApp)
                app.PlotSkyMapApp = ultrasat.planner.gui.PlotSkyMap(app.MainModule);                
            end
            app.PlotSkyMapApp.UIFigure.Visible = 'on';
        end

        % =================================================================      
        function plotCalibObj(app)
            % Plot Calibration Objects graph
            app.msglog('plotCalibObj');
            if ~app.hasPlanner(), return; end

            Planner = app.MainModule.Planner;

            % Get index of selected item
            UniqueTargetIndex = app.UITableUniqueTargets.Selection;
            if isempty(UniqueTargetIndex) || (UniqueTargetIndex < 1)
                return
            end

            if isempty(app.CalibObjTableApp) || ~isvalid(app.CalibObjTableApp)
                app.CalibObjTableApp = ultrasat.planner.gui.CalibObjTable(app.MainModule);                
            end
            app.CalibObjTableApp.UIFigure.Visible = 'on';                       

            try
                % N2: RA=215, Dec=60
                % Get and show in window
                app.UniqueTargetCalibObj = Planner.showCalibObj(UniqueTargetIndex, 'PlotSpectrum', false);
                if isempty(app.UniqueTargetCalibObj) || height(app.UniqueTargetCalibObj) == 0
                    app.setStatus('Warning', 'showCalibObj returned none')
                    return
                end

                app.GraphPlotUniqueTargetDropDown.Value = Planner.UniqTarg.Name(UniqueTargetIndex);

                app.CalibObjTableApp.UITableData.Data = app.UniqueTargetCalibObj;
                app.CalibObjTableApp.UITableData.ColumnName = app.UniqueTargetCalibObj.Properties.VariableNames;

                % Extract unique values from the 'obj' column of the table
                ObjValues = unique(app.UniqueTargetCalibObj.obj, 'stable');

                % Set the dropdown items to these values
                app.PlotCalibObjDropDown.Items = string(ObjValues);
                app.PlotCalibObjDropDown.Value = ObjValues{1};

                % Plot
                Planner.showCalibObj(UniqueTargetIndex, 'PlotSpectrum', true, 'subInd2plot', 1, 'AxesHandle', app.AxesGraphsPlot);
            catch ME
                app.msgex('plotCalibObj', ME);
            end
        end


        function plotCalibObjSub(app)
            % 
            try
                if ~isempty(app.MainModule.Planner)
                    UniqueTargetIndex = app.UITableUniqueTargets.Selection;
                    if isempty(UniqueTargetIndex) || (UniqueTargetIndex < 1)
                        return
                    end

                    Value = app.PlotCalibObjDropDown.Value;
                    CalObjIndex = find(strcmp(app.UniqueTargetCalibObj.obj, Value));
                    app.MainModule.Planner.showCalibObj(UniqueTargetIndex, 'PlotSpectrum', true, 'subInd2plot', CalObjIndex, 'AxesHandle', app.AxesGraphsPlot);
                end
            catch ME
                app.msgex('plotCalibObjSub', ME);
            end                            
        end


        function plotVisibility(app)
            % Plot Visibility graph
            Planner = app.MainModule.Planner;
            if isempty(Planner)
                return
            end

            % Get index of selected item
            UniqueTargetIndex = app.UITableUniqueTargets.Selection;
            if isempty(UniqueTargetIndex) || (UniqueTargetIndex < 1)
                return
            end

            try
                Planner.plotVisibility(UniqueTargetIndex, 'AxesHandle', app.AxesGraphsPlot);
            catch ME
                app.msgex('plotVisibility', ME);
            end
        end        


        function showGraphsPlotWindow(app)
            % @Todo
            if isempty(app.PlotGraphsApp) || ~isvalid(app.PlotGraphsApp)
                app.PlotGraphsApp = ultrasat.planner.gui.PlotGraphs(app.MainModule);                
            end
            app.PlotGraphsApp.UIFigure.Visible = 'on';            
        end


        function setGraphPlotTitle(app, Title)
            %
            % app.GraphPlotUniqueTargetEditField.Value = Title;
        end

        % =================================================================
        %                   Demos - POC - To Be Removed
        % =================================================================        
        function showSkyMapPlotDemo(app)
            % Update SkyMapPlot - @TODO with Yossi & Sasha
            app.msglog('showSkyMapPlot');

            % Get the UI obejct to be updated
            Axes = app.AxesSkymapPlot;

            % === Currently POC ===
            % Load a sample image (replace 'skymap.jpg' with your actual image file)
             img = imread('c:\temp\skymap.jpg');
            
            % Display the image in the axes
            imshow(img, 'Parent', Axes);
            
            % Add random data overlay (for demonstration purposes)
            hold(Axes, 'on');
            randomData = rand(100, 2) * size(img, 1); % Assuming the image is square
            plot(Axes, randomData(:,1), randomData(:,2), 'r*');
            hold(Axes, 'off');
    
            % Enable zooming
            zoom(app.UIFigure, 'on');            
        end        


        function showGraphsPlotDemo(app)
            % Update GraphsPlot - @TODO with Yossi & Sasha
            app.msglog('showGraphsPlot');

            % Get the UI obejct to be updated
            Axes = app.AxesGraphsPlot;
       
            % === Currently POC ===
            % Generate example data for three graphs
            x = linspace(0, 2*pi, 100); % X-axis data
            y1 = sin(x);                % First graph: sine wave
            y2 = cos(x);                % Second graph: cosine wave
            y3 = sin(x) .* cos(x);      % Third graph: sine * cosine
        
            % Clear axes before plotting (to remove any previous image or plots)
            cla(Axes);
        
            % Plot three graphs in different colors
            hold(Axes, 'on'); % Enable multiple plots on the same axes
            plot(Axes, x, y1, 'r', 'LineWidth', 2); % Red sine wave
            plot(Axes, x, y2, 'g', 'LineWidth', 2); % Green cosine wave
            plot(Axes, x, y3, 'b', 'LineWidth', 2); % Blue sine * cosine
            hold(Axes, 'off'); % Disable hold
        
            % Customize axes
            Axes.XLabel.String = 'X-Axis';
            Axes.YLabel.String = 'Y-Axis';
            Axes.Title.String = 'POC: Multiple Graphs on Axes';
    
            % Enable zooming
            zoom(app.UIFigure, 'on');            
        end             
    end

    % =====================================================================
    %                               
    % =====================================================================
    methods (Access = public)

        function updatePlanParams(app)
            % Update display with Plan parameters
            app.msglog('updatePlanParams')
            app.PlanTypeEditField.Value = app.MainModule.Planner.Type;
            app.UserNameEditField.Value = app.MainModule.Planner.AstPlanner;
            app.PlanTitleEditField.Value = app.MainModule.Planner.Title;
            app.StartTimeEditField.Value = app.MainModule.DateTime2Str(app.MainModule.Planner.StartTime);
            app.EndTimeEditField.Value = app.MainModule.DateTime2Str(app.MainModule.Planner.EndTime);
        end

        
        function Result = checkPlan(app)
            % 

            Result = false;
            try
                CheckStatus = app.MainModule.Planner.planSelfConsistencyCheck();
                if CheckStatus
                    app.MainModule.SelfConsistencyStatus = 'OK';
                    app.MainModule.setStatus('OK', 'self consistency: OK');
                    Result = true;
                else
                    app.MainModule.SelfConsistencyStatus = 'Issues found';
                    app.MainModule.setStatus('Error', 'self consistency: issues found');
                end
            catch ME
                app.msgex('planSelfConsistencyCheck failed', ME);
            end            
        end


        function showCheckTimes(app)
            % Update the screen with CheckTimes from the Planner object
            %app.CheckStartTimeEditField.Value = app.MainModule.DateTime2Str( app.MainModule.Planner.CheckTimes(1) );
            %app.CheckEndTimeEditField.Value = app.MainModule.DateTime2Str( app.MainModule.Planner.CheckTimes(2) );
        end        


        function updatePlannerCheckTimes(app)
            % Update Planner.CheckTimes with values from the edit fields
            try                
                if ~isempty(app.MainModule.Planner)
                    app.MainModule.Planner.CheckTimes(1) = app.CheckStartTimeEditField.Value;
                    app.MainModule.Planner.CheckTimes(2) = app.CheckEndTimeEditField.Value;
                end
            catch ME
                app.msgex('CheckTimes', ME);
            end            
            app.showCheckTimes();        
        end        
    end

    % =====================================================================
    %                           Validate % Submit    
    % =====================================================================
    methods (Access = public)    

        function validate(app)
            % Validate plan by sending it to the Validation service
            app.msglog('validate');            
            if ~app.isLogin('Message', true)
                return;
            end
            
            % Must save before submit
            if ~app.hasPlanner(), return; end		
            %if app.MainModule.Modified
            %    if ~strcmp(AppUtils.askYesNo(app, 'The plan was modified and not saved. You must save it before validation. Do you want to save your changes?', 'Confirm'), 'Yes')
            %        return;
            %    end                
            %end
            %app.savePlan();

            % Verify
            %if ~strcmp(AppUtils.askYesNo(app, 'Send plan with GCS Validator?', 'Confirm'), 'Yes')
            %    return;
            %end

            app.showPleaseWait('Validating your plan...');
            try
                app.MainModule.Planner.validate(app.MainModule.ApiClient);
            catch ME
                app.msgex('validate', ME);
            end
            app.closePleaseWait();
            app.MainModule.ValidateStatus = 'OK';
            app.updateStatus();
        end        


        function updateValidateStatus(app)
            app.setStatusField(app.ValidationStatusEditField, app.MainModule.ValidateStatus, app.MainModule.ValidateStatusText);
        end


        function submit(app)
            % Submit plan by sending it to Mission Control service
            app.msglog('submit');
            if ~app.isLogin()
                return;
            end

            % Must save before submit
            if ~app.hasPlanner(), return; end		
            if app.MainModule.Modified
                if ~strcmp(AppUtils.askYesNo(app, 'The plan was modified and not saved. You must save it before submitting. Do you want to save your changes?', 'Confirm'), 'Yes')
                    return;
                end                
            end
            app.savePlan();

            % Verify
            if ~strcmp(AppUtils.askYesNo(app, 'Submit this plan to Mission Control? Are you sure?', 'Confirm'), 'Yes')
                return;
            end

            app.showPleaseWait('Submitting your plan...');
            try
                app.MainModule.Planner.submit(app.MainModule.ApiClient);
                %Plan = [];
                %app.MainModule.Client.submit(Plan);
            catch ME
                app.msgex('submit', ME);
            end
            app.closePleaseWait();
            app.updateStatus();
        end        
    end

    % =====================================================================
    %                               Status
    % =====================================================================    

    methods (Access = public)            

        function applyPlanStatus()
            if app.hasPlanner()
                if strcmp(app.MainModule.Planner.Status, 'Submitted')
                    app.setReadOnly(true);
                else
                    app.setReadOnly(false);
                end
            else
            end
        end


        function setReadOnly(app, ReadOnly)
            %
            app.ReadOnly = ReadOnly;
            app.AllowEdit = ~ReadOnly;            
        end


        function Result = isReadOnly(app)
            Result = app.ReadOnly;
        end

        function Result = isReadOnlyMsg(app)
            Result = app.ReadOnly;
            if app.ReadOnly
                uialert(app.UIFigure, 'Plan is read-only', 'Message', 'Icon', 'success');                            
            end
        end        


        function setModified(app)
            % Mark the plan as modified (i.e. required to be saved/discarded)
            % if ~app.MainModule.Modified
                app.msglog('setModified: modified')
                app.MainModule.setModified();
                app.ModifiedLabel.Text = 'Modified';

                app.SaveButton.Enable = 'on';
            % end
        end


        function clearModified(app)
            % Clear the Modified flag and status
            if app.MainModule.Modified
                app.msglog('clearModified')
            end            
            app.MainModule.clearModified();
            app.ModifiedLabel.Text = '';
            app.SaveButton.Enable = 'off';
        end


        function Result = needSave(app, AskSave)
            % 
            if app.MainModule.Modified
                if AskSave
                    if strcmp(AppUtils.askYesNo(app, 'Your changes are not saved. Save or discard?', 'Save or discard'), 'Yes')
                        try
                            app.savePlan();
                        catch ME
                        end
                        Result = true;
                    else
                        Result = true;
                    end
                else
                    Result = true;
                end
            else
                Result = false;
            end
        end


        function setStatus(app, Status, Text)
            % Update the status panel with new status
            app.msglog(sprintf('setStatus: %s - %s', Status, Text));
            app.MainModule.setStatus(Status, Text);
            app.updateStatus();
        end


        function setStatusEx(app, Title, ME)
            % Update the status panel with exception message
            app.MainModule.setStatus('Error', sprintf('%s - %s', Title, ME.message));
            app.updateStatus();
        end


        function updateStatus(app)
            % @Todo - ??

            app.setStatusField(app.StatusTextArea, app.MainModule.CurrentStatus, app.MainModule.StatusText);
            %app.setStatusField(app.BuildStatusEditField, app.MainModule.BuildStatus);

            Planner = app.MainModule.Planner;
            if ~isempty(Planner)
                app.BuildTimeEditField.Value = app.MainModule.DateTime2Str(Planner.ScheduledTime);
                app.ValidationTimeEditField.Value = app.MainModule.DateTime2Str(Planner.ValidatedTime);
                app.SubmitTimeEditField.Value = app.MainModule.DateTime2Str(Planner.SubmittedTime);
            else
                app.BuildTimeEditField.Value = '';
                app.ValidationTimeEditField.Value = '';
                app.SubmitTimeEditField.Value = '';
            end
        end


        function setStatusField(app, EditField, Status, StatusText)
            % Set the background color of the EditField based on the Status value.
            % Valid values for Status: OK, Warning, Error, (empty)

            if isempty(StatusText)
                StatusText = '';
            end
            EditField.Value = StatusText;

            % Logic for background color
            if strcmp(Status, 'OK')
                % Light gray-green for 'OK'
                EditField.BackgroundColor = [0.8, 0.9, 0.8];
            elseif strcmp(Status, 'Warning')
                % Light yellow for 'Warning'
                EditField.BackgroundColor = [1.0, 1.0, 0.8];                
            elseif ~isempty(Status)
                % Light red for non-empty status that is not 'OK'
                EditField.BackgroundColor = [1, 0.8, 0.8];
            else
                % Light gray for empty status
                EditField.BackgroundColor = [0.9, 0.9, 0.9];
            end
        end

    end

    % =====================================================================
    %                         
    % =====================================================================

    methods (Access = public)    

     
    end

    % =====================================================================
    %                   Low Level & Utility Functions
    % =====================================================================

    methods (Access = public)        

        function Result = checkSelectedTableRow(app, StatusTab_2, Index)
            %
            Result = ~isempty(Index) && Index >= 1 && Index <= height(Tab);
        end

        function Result = getSelectedTableRowAsStruct(app, StatusTab_2, Index)
            %
            Result = [];
            if ~isempty(Index) && Index >= 1 && Index <= height(Tab)
                Result = table2struct(Tab(Index, :));
            end                
        end        

        function Result = getUniqueTargetsIndexByOrderColumn(app, Data)
            % Extract row indices for non-empty 'Order' values, sorted by 'Order'
            
            % Check if all values in 'Order' column are empty and replace with row numbers if needed
            if all(cellfun(@(x) isempty(strtrim(x)), Data.Order)) || all(isnan(str2double(Data.Order)))
                Data.Order = string(1:height(Data))';  %arrayfun(@num2str, (1:height(Data))', 'UniformOutput', false);
            end            

            % Convert to cell array if necessary (handles both strings and chars)
            if iscell(Data.Order) || isstring(Data.Order)
                % Trim whitespace and convert empty strings to NaN for filtering
                trimmedOrder = strtrim(Data.Order);
                isValid = ~strcmp(trimmedOrder, "") & ~strcmp(trimmedOrder, " ");  % Check for truly empty strings
                Data.Order(~isValid) = NaN;  % Replace empty strings with NaN
                Data.Order = str2double(Data.Order); % Convert valid numeric strings to doubles
            end
        
            % Find non-empty (non-NaN) rows
            nonEmptyRows = find(~isnan(Data.Order));
        
            % Sort by 'Order' column
            [~, sortedIdx] = sort(Data.Order(nonEmptyRows));
        
            % Return sorted row indices
            Result = nonEmptyRows(sortedIdx);
            Result = Result';
        end
        

        function Status = showModal(app, FormApp)
            % Show modal window and return status
            appName = class(FormApp);
            app.msglog(sprintf('showModal: %s', appName));
            uiwait(FormApp.UIFigure);
            FormApp.UIFigure.Visible = 'off';
            Status = FormApp.Status;
            app.msglog(sprintf('showModal: %s - returned, Status=%s', appName, Status));
        end

        % =================================================================

        function msglog(app, s)
            % Log message to console & file
            app.MainModule.msglog(s);            
        end


        function msgex(app, s, ME)
            % Log exception to console & file, display popup message to the user
            app.setStatus('Error', ME.message)
            app.MainModule.msglog(sprintf('Exception: %s - %s', s, ME.message));
            AppUtils.msgError(app, ME.message, s);
        end        


        function debugSave(app, FileName, Obj)
            FileName = fullfile(app.MainModule.DebugPath, FileName);
            app.msglog(sprintf('debugSave: %s', FileName));
            save(FileName, 'Obj')
        end


        function showPleaseWait(app, Message)
            % Shows a spinner            
            app.PleaseWait = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', Message, 'Indeterminate', 'on');  
        end


        function closePleaseWait(app)
            if ~isempty(app.PleaseWait)
                close(app.PleaseWait);
                app.PleaseWait = [];
            end
        end


        function result = hasPlanner(app)
            result = ~isempty(app.MainModule.Planner);
            if ~result
                app.msglog('hasPlanner: None');
            end
        end
        
    end

    % =====================================================================
    %
    %               AppDesigner Callbacks are Generated Below
    %
    % =====================================================================    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This function is automaticalled called on application startup
            app.init();            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.createNewPlan();
        end

        % Callback function
        function UniqueButtonPushed(app, event)

        end

        % Menu selected function: ConnectLoginMenu
        function ConnectLoginMenuSelected(app, event)
            app.login();
        end

        % Menu selected function: DisconnectLogoutMenu
        function DisconnectLogoutMenuSelected(app, event)
            app.logout();
        end

        % Callback function
        function NewMenuSelected(app, event)
            app.createNewPlan();
        end

        % Callback function
        function OpenMenuSelected(app, event)
            app.openPlan();           
        end

        % Callback function
        function SaveMenuSelected(app, event)
            app.savePlan();
        end

        % Callback function: not associated with a component
        function ExitPlannerMenuSelected(app, event)
            % @Todo - check for unsaved changes
            answer = questdlg('Are you sure you want to exit the Observaion Planner?', 'Confirm exit', 'Yes', 'No', 'No');
            if strcmpi(answer, 'Yes')
                app.delete();
            end
        end

        % Callback function
        function UniqueTargetsMenuSelected(app, event)
            app.showUniqueTargetsWindow();
        end

        % Callback function
        function ParametersMenuSelected(app, event)
            app.showPlanParamsWindow();
        end

        % Callback function
        function ValidateMenuSelected(app, event)
            app.validate();
        end

        % Callback function
        function SubmitMenuSelected(app, event)
            app.submit();
        end

        % Size changed function: ApprovedTargetsPanel
        function ApprovedTargetsPanelSizeChanged(app, event)
            % position = app.ApprovedTargetsPanel.Position;
            % @Todo - Do we need this callback???
        end

        % Size changed function: PlanPanel
        function PlanPanelSizeChanged(app, event)
            % position = app.PlanPanel.Position;
            % @Todo - Do we need this callback???
        end

        % Size changed function: UniqueTargetsPanel
        function UniqueTargetsPanelSizeChanged(app, event)
            % position = app.UniqueTargetsPanel.Position;
            % @Todo - Do we need this callback???
        end

        % Callback function
        function SkyMapButtonPushed(app, event)
            app.showSkyMapPlot();
        end

        % Callback function
        function GraphsButtonPushed(app, event)
            app.showGraphsPlot();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.showAboutWindow();
        end

        % Callback function
        function AddUniqueButtonPushed(app, event)
            app.addUniqueTarget();
        end

        % Button pushed function: AddUniqueTargetButton
        function AddUniqueTargetButtonPushed(app, event)
            app.addUniqueTarget();
        end

        % Button pushed function: EditUniqueTargetButton
        function EditUniqueTargetButtonPushed(app, event)
            app.editUniqueTarget();
        end

        % Callback function
        function RefreshUniqueTargetsButtonPushed(app, event)
            app.showUniqueTargets();
        end

        % Selection changed function: UITableUniqueTargets
        function UITableUniqueTargetsSelectionChanged(app, event)
            %app.UniqueTargetsIndices = event.Indices;
            selection = app.UITableUniqueTargets.Selection;
            app.msglog(sprintf('Unique target selected: %d', selection));
            app.uniqueTargetSelected(selection);
        end

        % Button pushed function: SNRCalcButton
        function SNRCalcButtonPushed(app, event)
            app.showSnrCalculator();
        end

        % Callback function
        function RefreshPlanTargetsButtonPushed(app, event)
            app.showPlanTargets();
        end

        % Button pushed function: RefreshApprovedTargetsButton
        function RefreshApprovedTargetsButtonPushed(app, event)
            % Refresh
            app.retrieveApprovedTargets();
        end

        % Menu selected function: ObservationPlannerHelpMenu
        function ObservationPlannerHelpMenuSelected(app, event)
            web('https://planner.ultrasatsoc.org/');
        end

        % Callback function
        function NewMenuSelected2(app, event)

        end

        % Callback function
        function OpenMenuSelected2(app, event)
            %
        end

        % Callback function
        function SaveMenuSelected2(app, event)
            app.savePlan();
        end

        % Callback function
        function CloseMenu_2Selected(app, event)
            %
        end

        % Callback function
        function DeleteMenuSelected(app, event)
            app.deletePlan();
        end

        % Callback function
        function SavePlantoFileMenuSelected(app, event)
            app.savePlanToFile();
        end

        % Callback function
        function LoadPlanfromFileMenuSelected(app, event)
            app.loadPlanFromFile();
        end

        % Menu selected function: AddUniqueTargetMenu
        function AddUniqueTargetMenuSelected(app, event)
            app.addUniqueTarget();
        end

        % Menu selected function: EditUniqueTargetMenu
        function EditUniqueTargetMenuSelected(app, event)
            app.editUniqueTarget();
        end

        % Menu selected function: DeleteUniqueTargetMenu
        function DeleteUniqueTargetMenuSelected(app, event)
            app.deleteUniqueTarget();
        end

        % Menu selected function: ClearAllUniqueTargetsMenu
        function ClearAllUniqueTargetsMenuSelected(app, event)
            app.clearUniqueTargets();
        end

        % Menu selected function: ViewUniqueTargetsTableMenu
        function ViewUniqueTargetsTableMenuSelected(app, event)
            %
        end

        % Menu selected function: SaveUniqueTargetsToFileMenu
        function SaveUniqueTargetsToFileMenuSelected(app, event)
            app.saveUniqueTargetsToTextFile();
        end

        % Menu selected function: LoadUniqueTargetsFromFileMenu
        function LoadUniqueTargetsFromFileMenuSelected(app, event)
            %
            app.loadUniqueTargetsFromFile();
        end

        % Menu selected function: ViewSkyMapPlotWindowMenu
        function ViewSkyMapPlotWindowMenuSelected(app, event)
            app.showSkyMapPlotWindow();
        end

        % Menu selected function: ViewGraphsPlotWindowMenu
        function ViewGraphsPlotWindowMenuSelected(app, event)
            %
            app.showGraphsPlotWindow();
        end

        % Menu selected function: EditPlanTargetMenu
        function EditPlanTargetMenuSelected(app, event)
            app.editPlanTarget();
        end

        % Menu selected function: DeletePlanTargetMenu
        function DeletePlanTargetMenuSelected(app, event)
            %
            app.deletePlanTarget();
        end

        % Menu selected function: ClearAllPlanTargetsMenu
        function ClearAllPlanTargetsMenuSelected(app, event)
            app.clearPlanTargets();
        end

        % Menu selected function: ViewPlanTableMenu
        function ViewPlanTableMenuSelected(app, event)
            %
        end

        % Callback function
        function NewButtonPushed2(app, event)
           
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.openPlan();            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savePlan();
        end

        % Button pushed function: ParamsButton
        function ParamsButtonPushed(app, event)
            app.showPlanParamsWindow();
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.MainModule.setStatus('Error', 'Errors found');
            app.updateStatus();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.submit();
        end

        % Callback function
        function GetApprovedButtonPushed(app, event)

        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            app.build();
        end

        % Button pushed function: EditPlanTargetButton
        function EditPlanTargetButtonPushed(app, event)
            app.editPlanTarget();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.login();
        end

        % Callback function
        function BuildMenuSelected(app, event)
            app.build();
        end

        % Button pushed function: LoadUniqueTargetsButton
        function LoadUniqueTargetsButtonPushed(app, event)
            app.loadUniqueTargetsFromFile();
        end

        % Menu selected function: LogWindowMenu
        function LogWindowMenuSelected(app, event)
            app.showLogger();
        end

        % Callback function
        function PlotCalibButtonPushed(app, event)
            app.plotCalibObj();
        end

        % Button pushed function: RefreshApprovedTargetsButton_2
        function RefreshApprovedTargetsButton_2Pushed(app, event)
            app.MainModule.clearStatus();
            app.updateStatus();
        end

        % Button pushed function: CheckPlanTargetsButton
        function CheckPlanTargetsButtonPushed(app, event)
            app.checkPlan();
        end

        % Selection changed function: UITablePlanTargets
        function UITablePlanTargetsSelectionChanged(app, event)
            selection = app.UITablePlanTargets.Selection;
            app.msglog(sprintf('Plan target selected: %d', selection));
            app.planTargetSelected(selection);                        
        end

        % Selection changed function: UITableApprovedTargets
        function UITableApprovedTargetsSelectionChanged(app, event)
            selection = app.UITableApprovedTargets.Selection;
            app.msglog(sprintf('Approved target selected: %d', selection));
            app.approvedTargetSelected(selection);            
        end

        % Button pushed function: DuplicateButton
        function DuplicateButtonPushed(app, event)
            app.duplicatePlan();
        end

        % Callback function
        function CheckTimesUpdateButtonPushed(app, event)
            app.updatePlannerCheckTimes();
        end

        % Callback function
        function PlotVisibilityButtonPushed(app, event)
            app.plotVisibility();
        end

        % Value changed function: PlotCalibObjDropDown
        function PlotCalibObjDropDownValueChanged(app, event)
            app.plotCalibObjSub();
        end

        % Button pushed function: UpdateSkyMapButton
        function UpdateSkyMapButtonPushed(app, event)
            app.showSkyMapPlot();
        end

        % Button pushed function: RefreshApprovedTargetsButton_3
        function RefreshApprovedTargetsButton_3Pushed(app, event)
            app.clearApprovedTargets();
        end

        % Button pushed function: EditPlanTargetButton_6
        function EditPlanTargetButton_6Pushed(app, event)
            app.adjustGroupStartTime();
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            app.showPlanAll();
        end

        % Button pushed function: EditPlanTargetButton_5
        function EditPlanTargetButton_5Pushed(app, event)
            app.clearUniqueTargets();
        end

        % Button pushed function: EditPlanTargetButton_3
        function EditPlanTargetButton_3Pushed(app, event)
            app.clearPlanTargets();
        end

        % Clicked callback: UITableUniqueTargets
        function UITableUniqueTargetsClicked(app, event)
            %
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;            
            app.uniqueTargetClick();
        end

        % Double-clicked callback: UITableUniqueTargets
        function UITableUniqueTargetsDoubleClicked(app, event)
            %
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.uniqueTargetDoubleClick();
        end

        % Clicked callback: UITablePlanTargets
        function UITablePlanTargetsClicked(app, event)
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.planRowClick();
        end

        % Double-clicked callback: UITablePlanTargets
        function UITablePlanTargetsDoubleClicked(app, event)
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.planRowDoubleClick();            
        end

        % Button pushed function: BuildStatusButton
        function BuildStatusButtonPushed(app, event)
            app.showBuildStatusWindow();
        end

        % Button pushed function: ValidationStatusButton
        function ValidationStatusButtonPushed(app, event)
            app.showValidationStatusWindow();
        end

        % Button pushed function: SubmitStatusButton
        function SubmitStatusButtonPushed(app, event)
            app.showSubmitStatusWindow();
        end

        % Menu selected function: NewMenu
        function NewMenuSelected3(app, event)
            app.createNewPlan();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected3(app, event)
            app.openPlan();
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected3(app, event)
            app.savePlan();
        end

        % Menu selected function: DuplicateMenu
        function DuplicateMenuSelected(app, event)
            app.duplicatePlan();
        end

        % Menu selected function: CloseMenu_2
        function CloseMenu_2Selected2(app, event)
            app.closePlan();
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected2(app, event)
            app.deletePlan();
        end

        % Menu selected function: SaveToLocalFileMenu
        function SaveToLocalFileMenuSelected(app, event)
            app.savePlanToFile();
        end

        % Menu selected function: OpenFromLocalFileMenu
        function OpenFromLocalFileMenuSelected(app, event)
            app.loadPlanFromFile();
        end

        % Menu selected function: ExitPlannerMenu
        function ExitPlannerMenuSelected2(app, event)
            app.exitPlanner();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected3, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected3, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected3, true);
            app.SaveMenu.Text = 'Save';
            app.DuplicateMenu = uimenu(app.FileMenu);
            app.DuplicateMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicateMenuSelected, true);
            app.DuplicateMenu.Text = 'Duplicate';
            app.CloseMenu_2 = uimenu(app.FileMenu);
            app.CloseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CloseMenu_2Selected2, true);
            app.CloseMenu_2.Text = 'Close';
            app.DeleteMenu = uimenu(app.FileMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected2, true);
            app.DeleteMenu.Text = 'Delete';
            app.SaveToLocalFileMenu = uimenu(app.FileMenu);
            app.SaveToLocalFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveToLocalFileMenuSelected, true);
            app.SaveToLocalFileMenu.Text = 'Save To Local File';
            app.OpenFromLocalFileMenu = uimenu(app.FileMenu);
            app.OpenFromLocalFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFromLocalFileMenuSelected, true);
            app.OpenFromLocalFileMenu.Text = 'Open From Local File';
            app.Menu_2 = uimenu(app.FileMenu);
            app.Menu_2.Text = '______________________';
            app.ConnectLoginMenu = uimenu(app.FileMenu);
            app.ConnectLoginMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectLoginMenuSelected, true);
            app.ConnectLoginMenu.Text = 'Connect && Login';
            app.DisconnectLogoutMenu = uimenu(app.FileMenu);
            app.DisconnectLogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectLogoutMenuSelected, true);
            app.DisconnectLogoutMenu.Text = 'Disconnect (Logout)';
            app.ExitPlannerMenu = uimenu(app.FileMenu);
            app.ExitPlannerMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitPlannerMenuSelected2, true);
            app.ExitPlannerMenu.Text = 'Exit Planner';
            app.TargetsMenu = uimenu(app.UIFigure);
            app.TargetsMenu.Text = 'Targets';
            app.AddUniqueTargetMenu = uimenu(app.TargetsMenu);
            app.AddUniqueTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @AddUniqueTargetMenuSelected, true);
            app.AddUniqueTargetMenu.Text = 'Add Unique Target';
            app.EditUniqueTargetMenu = uimenu(app.TargetsMenu);
            app.EditUniqueTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @EditUniqueTargetMenuSelected, true);
            app.EditUniqueTargetMenu.Text = 'Edit Unique Target';
            app.DeleteUniqueTargetMenu = uimenu(app.TargetsMenu);
            app.DeleteUniqueTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUniqueTargetMenuSelected, true);
            app.DeleteUniqueTargetMenu.Text = 'Delete Unique Target';
            app.ClearAllUniqueTargetsMenu = uimenu(app.TargetsMenu);
            app.ClearAllUniqueTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllUniqueTargetsMenuSelected, true);
            app.ClearAllUniqueTargetsMenu.Text = 'Clear All Unique Targets';
            app.ViewUniqueTargetsTableMenu = uimenu(app.TargetsMenu);
            app.ViewUniqueTargetsTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewUniqueTargetsTableMenuSelected, true);
            app.ViewUniqueTargetsTableMenu.Text = 'View Unique Targets Table';
            app.SaveUniqueTargetsToFileMenu = uimenu(app.TargetsMenu);
            app.SaveUniqueTargetsToFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveUniqueTargetsToFileMenuSelected, true);
            app.SaveUniqueTargetsToFileMenu.Text = 'Save Unique Targets To File';
            app.LoadUniqueTargetsFromFileMenu = uimenu(app.TargetsMenu);
            app.LoadUniqueTargetsFromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadUniqueTargetsFromFileMenuSelected, true);
            app.LoadUniqueTargetsFromFileMenu.Text = 'Load Unique Targets From File';
            app.PlanMenu_2 = uimenu(app.UIFigure);
            app.PlanMenu_2.Text = 'Plan';
            app.EditPlanTargetMenu = uimenu(app.PlanMenu_2);
            app.EditPlanTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPlanTargetMenuSelected, true);
            app.EditPlanTargetMenu.Text = 'Edit Plan Target';
            app.DeletePlanTargetMenu = uimenu(app.PlanMenu_2);
            app.DeletePlanTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletePlanTargetMenuSelected, true);
            app.DeletePlanTargetMenu.Text = 'Delete Plan Target';
            app.ClearAllPlanTargetsMenu = uimenu(app.PlanMenu_2);
            app.ClearAllPlanTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllPlanTargetsMenuSelected, true);
            app.ClearAllPlanTargetsMenu.Text = 'Clear All Plan Targets';
            app.ViewPlanTableMenu = uimenu(app.PlanMenu_2);
            app.ViewPlanTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewPlanTableMenuSelected, true);
            app.ViewPlanTableMenu.Text = 'View Plan Table';
            app.Menu_3 = uimenu(app.PlanMenu_2);
            app.Menu_3.Text = '___________________';
            app.ParametersMenu = uimenu(app.PlanMenu_2);
            app.ParametersMenu.Text = 'Parameters';
            app.Menu = uimenu(app.PlanMenu_2);
            app.Menu.Text = '___________________';
            app.BuildMenu = uimenu(app.PlanMenu_2);
            app.BuildMenu.Text = 'Build';
            app.ValidateMenu = uimenu(app.PlanMenu_2);
            app.ValidateMenu.Text = 'Validate';
            app.SubmitMenu = uimenu(app.PlanMenu_2);
            app.SubmitMenu.Text = 'Submit';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.ViewSkyMapPlotWindowMenu = uimenu(app.PlotsMenu);
            app.ViewSkyMapPlotWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewSkyMapPlotWindowMenuSelected, true);
            app.ViewSkyMapPlotWindowMenu.Text = 'View Sky Map Plot Window';
            app.ViewGraphsPlotWindowMenu = uimenu(app.PlotsMenu);
            app.ViewGraphsPlotWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewGraphsPlotWindowMenuSelected, true);
            app.ViewGraphsPlotWindowMenu.Text = 'View Graphs Plot Window';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.RefreshMenu = uimenu(app.ViewMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.LogWindowMenu = uimenu(app.ViewMenu);
            app.LogWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @LogWindowMenuSelected, true);
            app.LogWindowMenu.Text = 'Log Window';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SNRCalculatorMenu = uimenu(app.ToolsMenu);
            app.SNRCalculatorMenu.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.SNRCalculatorMenu.Text = 'SNR Calculator';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ObservationPlannerHelpMenu = uimenu(app.HelpMenu);
            app.ObservationPlannerHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ObservationPlannerHelpMenuSelected, true);
            app.ObservationPlannerHelpMenu.Text = 'Observation Planner Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Panel = uipanel(app.UIFigure);
            app.NewButton = uibutton(app.Panel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Tooltip = {'Create new observation plan'};
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open existing observation plan from database'};
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save observation plan to database'};
            app.SaveButton.Text = 'Save';
            app.SubmitButton = uibutton(app.Panel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Tooltip = {'Send plan to Mission Control for approval'};
            app.SubmitButton.Text = 'Submit';
            app.ValidateButton = uibutton(app.Panel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Tooltip = {'Send plan for validation (may take up to 30 seconds)'};
            app.ValidateButton.Text = 'Validate';
            app.ParamsButton = uibutton(app.Panel, 'push');
            app.ParamsButton.ButtonPushedFcn = createCallbackFcn(app, @ParamsButtonPushed, true);
            app.ParamsButton.Tooltip = {'Show (and edit) observation plan parameters'};
            app.ParamsButton.Text = 'Params';
            app.ConnectButton = uibutton(app.Panel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Tooltip = {'Connect to server and login'};
            app.ConnectButton.Text = 'Connect';
            app.SNRCalcButton = uibutton(app.Panel, 'push');
            app.SNRCalcButton.ButtonPushedFcn = createCallbackFcn(app, @SNRCalcButtonPushed, true);
            app.SNRCalcButton.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.SNRCalcButton.Text = 'SNR Calc';
            app.ConnectionStatusEditFieldLabel = uilabel(app.Panel);
            app.ConnectionStatusEditFieldLabel.Text = 'Connection Status';
            app.ConnectionStatusEditField = uieditfield(app.Panel, 'text');
            app.ConnectionStatusEditField.Editable = 'off';
            app.ConnectionStatusEditField.Tooltip = {'Server connection & login status'};
            app.ModifiedLabel = uilabel(app.Panel);
            app.ModifiedLabel.Tooltip = {'Is current plan modified sience last saved?'};
            app.ModifiedLabel.Text = 'Modified';
            app.DuplicateButton = uibutton(app.Panel, 'push');
            app.DuplicateButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateButtonPushed, true);
            app.DuplicateButton.Tooltip = {'Duplicate current observation plan'};
            app.DuplicateButton.Text = 'Duplicate';
            app.RetractButton = uibutton(app.Panel, 'push');
            app.RetractButton.Tooltip = {'Send plan to Mission Control for approval'};
            app.RetractButton.Text = 'Retract !!!';
            app.UniqueTargetsPanel = uipanel(app.UIFigure);
            app.UniqueTargetsPanel.Title = 'Unique Targets';
            app.UniqueTargetsPanel.SizeChangedFcn = createCallbackFcn(app, @UniqueTargetsPanelSizeChanged, true);
            app.UITableUniqueTargets = uitable(app.UniqueTargetsPanel);
            app.UITableUniqueTargets.ColumnName = '';
            app.UITableUniqueTargets.RowName = {};
            app.UITableUniqueTargets.ColumnEditable = true;
            app.UITableUniqueTargets.DoubleClickedFcn = createCallbackFcn(app, @UITableUniqueTargetsDoubleClicked, true);
            app.UITableUniqueTargets.ClickedFcn = createCallbackFcn(app, @UITableUniqueTargetsClicked, true);
            app.UITableUniqueTargets.SelectionChangedFcn = createCallbackFcn(app, @UITableUniqueTargetsSelectionChanged, true);
            app.Panel_6 = uipanel(app.UniqueTargetsPanel);
            app.AddUniqueTargetButton = uibutton(app.Panel_6, 'push');
            app.AddUniqueTargetButton.ButtonPushedFcn = createCallbackFcn(app, @AddUniqueTargetButtonPushed, true);
            app.AddUniqueTargetButton.Tooltip = {'Add new unique target'};
            app.AddUniqueTargetButton.Text = 'Add';
            app.EditUniqueTargetButton = uibutton(app.Panel_6, 'push');
            app.EditUniqueTargetButton.ButtonPushedFcn = createCallbackFcn(app, @EditUniqueTargetButtonPushed, true);
            app.EditUniqueTargetButton.Tooltip = {'Edit the selected unique target'};
            app.EditUniqueTargetButton.Text = 'Edit';
            app.LoadUniqueTargetsButton = uibutton(app.Panel_6, 'push');
            app.LoadUniqueTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUniqueTargetsButtonPushed, true);
            app.LoadUniqueTargetsButton.Tooltip = {'Load unique targets from text file'};
            app.LoadUniqueTargetsButton.Text = 'Load';
            app.EditPlanTargetButton_4 = uibutton(app.Panel_6, 'push');
            app.EditPlanTargetButton_4.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_4.Text = 'Delete';
            app.EditPlanTargetButton_5 = uibutton(app.Panel_6, 'push');
            app.EditPlanTargetButton_5.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_5Pushed, true);
            app.EditPlanTargetButton_5.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_5.Text = 'Clear All';
            app.PlanPanel = uipanel(app.UIFigure);
            app.PlanPanel.Title = 'Plan';
            app.PlanPanel.SizeChangedFcn = createCallbackFcn(app, @PlanPanelSizeChanged, true);
            app.UITablePlanTargets = uitable(app.PlanPanel);
            app.UITablePlanTargets.ColumnName = '';
            app.UITablePlanTargets.RowName = {};
            app.UITablePlanTargets.DoubleClickedFcn = createCallbackFcn(app, @UITablePlanTargetsDoubleClicked, true);
            app.UITablePlanTargets.ClickedFcn = createCallbackFcn(app, @UITablePlanTargetsClicked, true);
            app.UITablePlanTargets.SelectionChangedFcn = createCallbackFcn(app, @UITablePlanTargetsSelectionChanged, true);
            app.Panel_4 = uipanel(app.PlanPanel);
            app.BuildButton = uibutton(app.Panel_4, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Tooltip = {'Build plan (depends on plan type)'};
            app.BuildButton.Text = 'Build';
            app.CheckPlanTargetsButton = uibutton(app.Panel_4, 'push');
            app.CheckPlanTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckPlanTargetsButtonPushed, true);
            app.CheckPlanTargetsButton.Tooltip = {'Perform plan consistency check'};
            app.CheckPlanTargetsButton.Text = 'Check';
            app.EditPlanTargetButton = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButtonPushed, true);
            app.EditPlanTargetButton.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton.Text = 'Edit';
            app.EditPlanTargetButton_2 = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton_2.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_2.Text = 'Delete';
            app.EditPlanTargetButton_3 = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_3Pushed, true);
            app.EditPlanTargetButton_3.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_3.Text = 'Clear All';
            app.EditPlanTargetButton_6 = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton_6.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_6Pushed, true);
            app.EditPlanTargetButton_6.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_6.Text = 'Group';
            app.ApprovedTargetsPanel = uipanel(app.UIFigure);
            app.ApprovedTargetsPanel.Title = 'Approved Targets';
            app.ApprovedTargetsPanel.SizeChangedFcn = createCallbackFcn(app, @ApprovedTargetsPanelSizeChanged, true);
            app.UITableApprovedTargets = uitable(app.ApprovedTargetsPanel);
            app.UITableApprovedTargets.ColumnName = '';
            app.UITableApprovedTargets.RowName = {};
            app.UITableApprovedTargets.SelectionChangedFcn = createCallbackFcn(app, @UITableApprovedTargetsSelectionChanged, true);
            app.Panel_5 = uipanel(app.ApprovedTargetsPanel);
            app.RefreshApprovedTargetsButton = uibutton(app.Panel_5, 'push');
            app.RefreshApprovedTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshApprovedTargetsButtonPushed, true);
            app.RefreshApprovedTargetsButton.Tooltip = {'Refeatch data from uplanner object'};
            app.RefreshApprovedTargetsButton.Text = 'Retreive';
            app.RefreshApprovedTargetsButton_3 = uibutton(app.Panel_5, 'push');
            app.RefreshApprovedTargetsButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshApprovedTargetsButton_3Pushed, true);
            app.RefreshApprovedTargetsButton_3.Tooltip = {'Refeatch data from uplanner object'};
            app.RefreshApprovedTargetsButton_3.Text = 'Clear';
            app.PlotSkyMapPanel = uipanel(app.UIFigure);
            app.PlotSkyMapPanel.Title = 'Plot - Sky Map';
            app.AxesSkymapPlot = uiaxes(app.PlotSkyMapPanel);
            title(app.AxesSkymapPlot, 'Title')
            xlabel(app.AxesSkymapPlot, 'X')
            ylabel(app.AxesSkymapPlot, 'Y')
            zlabel(app.AxesSkymapPlot, 'Z')
            app.AxesSkymapPlot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AxesSkymapPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Panel_10 = uipanel(app.PlotSkyMapPanel);
            app.PlotFlagUniqueCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagUniqueCheckBox.Text = {'Unique'; 'Targets'};
            app.PlotFlagPlanCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagPlanCheckBox.Text = 'Plan';
            app.PlotFlagPlanCheckBox.Value = true;
            app.PlotFlagExtinctionCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagExtinctionCheckBox.Text = 'Extinction';
            app.PlotFlagCalibrationCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagCalibrationCheckBox.Text = {'Calibration'; 'Stars'};
            app.PlotFlagApprovedCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagApprovedCheckBox.Text = {'Approved'; 'Targets'};
            app.PlotFlagVisibleCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagVisibleCheckBox.Enable = 'off';
            app.PlotFlagVisibleCheckBox.Text = 'Visibility';
            app.UpdateSkyMapButton = uibutton(app.Panel_10, 'push');
            app.UpdateSkyMapButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSkyMapButtonPushed, true);
            app.UpdateSkyMapButton.Text = 'Update';
            app.cooSysLabel_4 = uilabel(app.Panel_10);
            app.cooSysLabel_4.Text = {'Coordinate'; 'System'};
            app.PlotCooSysDropDown = uidropdown(app.Panel_10);
            app.PlotCooSysDropDown.Items = {'Equatorial', 'Ecliptic', 'Galactic'};
            app.PlotCooSysDropDown.Value = 'Equatorial';
            app.PlotGraphsPanel = uipanel(app.UIFigure);
            app.PlotGraphsPanel.Title = 'Plot - Graphs';
            app.AxesGraphsPlot = uiaxes(app.PlotGraphsPanel);
            title(app.AxesGraphsPlot, 'Title')
            xlabel(app.AxesGraphsPlot, 'X')
            ylabel(app.AxesGraphsPlot, 'Y')
            zlabel(app.AxesGraphsPlot, 'Z')
            app.cooSysLabel_2 = uilabel(app.PlotGraphsPanel);
            app.cooSysLabel_2.Text = 'CalObj subInd';
            app.PlotCalibObjDropDown = uidropdown(app.PlotGraphsPanel);
            app.PlotCalibObjDropDown.Items = {};
            app.PlotCalibObjDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotCalibObjDropDownValueChanged, true);
            app.PlotCalibObjDropDown.Value = {};
            app.cooSysLabel_3 = uilabel(app.PlotGraphsPanel);
            app.cooSysLabel_3.Text = 'Unique Target';
            app.GraphPlotUniqueTargetDropDown = uidropdown(app.PlotGraphsPanel);
            app.GraphPlotUniqueTargetDropDown.Items = {};
            app.GraphPlotUniqueTargetDropDown.Value = {};
            app.ButtonGroup = uibuttongroup(app.PlotGraphsPanel);
            app.VisibilityButton = uiradiobutton(app.ButtonGroup);
            app.VisibilityButton.Text = 'Visibility';
            app.VisibilityButton.Value = true;
            app.CalibrationStarButton = uiradiobutton(app.ButtonGroup);
            app.CalibrationStarButton.Text = 'Calibration Star';
            app.UpdateSkyMapButton_2 = uibutton(app.PlotGraphsPanel, 'push');
            app.UpdateSkyMapButton_2.Text = 'CalObj Table';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlanParamsTab = uitab(app.TabGroup);
            app.PlanParamsTab.Title = 'Plan Params';
            app.PlanTypeEditFieldLabel = uilabel(app.PlanParamsTab);
            app.PlanTypeEditFieldLabel.Text = 'Plan Type';
            app.PlanTypeEditField = uieditfield(app.PlanParamsTab, 'text');
            app.PlanTypeEditField.Editable = 'off';
            app.UserNameEditFieldLabel = uilabel(app.PlanParamsTab);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UserNameEditField = uieditfield(app.PlanParamsTab, 'text');
            app.UserNameEditField.Editable = 'off';
            app.PlanTitleEditFieldLabel = uilabel(app.PlanParamsTab);
            app.PlanTitleEditFieldLabel.Text = 'Plan Title';
            app.PlanTitleEditField = uieditfield(app.PlanParamsTab, 'text');
            app.PlanTitleEditField.Editable = 'off';
            app.StartTimeEditFieldLabel = uilabel(app.PlanParamsTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.PlanParamsTab, 'text');
            app.StartTimeEditField.Editable = 'off';
            app.StartTimeEditField.Tooltip = {'Enter plan start time (i.e. 2024-12-04 00:00:00)'};
            app.StartTimeEditField.Value = '2025-01-01 00:00:00';
            app.EndTimeEditFieldLabel = uilabel(app.PlanParamsTab);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.PlanParamsTab, 'text');
            app.EndTimeEditField.Editable = 'off';
            app.Panel_8 = uipanel(app.UIFigure);
            app.StatusTextAreaLabel = uilabel(app.Panel_8);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.Panel_8);
            app.StatusTextArea.Editable = 'off';
            app.RefreshApprovedTargetsButton_2 = uibutton(app.Panel_8, 'push');
            app.RefreshApprovedTargetsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshApprovedTargetsButton_2Pushed, true);
            app.RefreshApprovedTargetsButton_2.Text = 'Clear';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.StatusTab_2 = uitab(app.TabGroup2);
            app.StatusTab_2.Title = 'Status';
            app.BuildEditFieldLabel = uilabel(app.StatusTab_2);
            app.BuildEditFieldLabel.Text = 'Build';
            app.BuildTimeEditField = uieditfield(app.StatusTab_2, 'text');
            app.BuildTimeEditField.Editable = 'off';
            app.BuildTimeEditField.Value = '2025-01-01 00:00:00';
            app.ValidationEditFieldLabel = uilabel(app.StatusTab_2);
            app.ValidationEditFieldLabel.Text = 'Validation';
            app.ValidationTimeEditField = uieditfield(app.StatusTab_2, 'text');
            app.ValidationTimeEditField.Editable = 'off';
            app.SubmitEditFieldLabel = uilabel(app.StatusTab_2);
            app.SubmitEditFieldLabel.Text = 'Submit';
            app.SubmitTimeEditField = uieditfield(app.StatusTab_2, 'text');
            app.SubmitTimeEditField.Editable = 'off';
            app.BuildShortStatusEditField = uieditfield(app.StatusTab_2, 'text');
            app.BuildShortStatusEditField.Editable = 'off';
            app.BuildShortStatusEditField.Value = 'OK';
            app.ValidationShortStatusEditField = uieditfield(app.StatusTab_2, 'text');
            app.ValidationShortStatusEditField.Editable = 'off';
            app.SubmitShortStatusEditField = uieditfield(app.StatusTab_2, 'text');
            app.SubmitShortStatusEditField.Editable = 'off';
            app.BuildStatusButton = uibutton(app.StatusTab_2, 'push');
            app.BuildStatusButton.ButtonPushedFcn = createCallbackFcn(app, @BuildStatusButtonPushed, true);
            app.BuildStatusButton.Tooltip = {'Load unique targets from text file'};
            app.BuildStatusButton.Text = '...';
            app.ValidationStatusButton = uibutton(app.StatusTab_2, 'push');
            app.ValidationStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationStatusButtonPushed, true);
            app.ValidationStatusButton.Tooltip = {'Load unique targets from text file'};
            app.ValidationStatusButton.Text = '...';
            app.SubmitStatusButton = uibutton(app.StatusTab_2, 'push');
            app.SubmitStatusButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitStatusButtonPushed, true);
            app.SubmitStatusButton.Tooltip = {'Load unique targets from text file'};
            app.SubmitStatusButton.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlannerMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end