classdef SaveUniqueTargetsToTextFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BrowseButton       matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        TextAreaLabel      matlab.ui.control.Label
        EnterfilenametoloadfromorpastetextbelowLabel  matlab.ui.control.Label
        FileNameEditField  matlab.ui.control.EditField
        Panel_2            matlab.ui.container.Panel
        SaveUniqueTargetsToLocalFileLabel  matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        CancelButton       matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule          % 
        Status              %
    end
    
    methods (Access = public)
        
        function setText(app, Text)
            app.TextArea.Value = Text;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Status = [];
            app.FileName = [];

            FName = app.FileNameEditField.Value;

            if ~isempty(FName)
                if isfile(FName)
                    % Show message - 'File already exist'
                    if strcmp(AppUtils.askYesNo(app, 'File already exist, overwrite?'), 'Yes')
                        app.FileName = FName;
                        app.Status = 'Save';
                        uiresume(app.UIFigure);                        
                    end
                else
                    app.FileName = FName;
                    app.Status = 'Save';
                    uiresume(app.UIFigure);
                end
            else
                % Show message 'File does not exist'
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Open "Save File" dialog
            [filename, pathname] = uiputfile('*.mat', 'Save As', app.Folder);
            
            % Check if the user pressed 'Cancel'
            if filename ~= 0
                fullFilePath = fullfile(pathname, filename);
                app.FileNameEditField.Value = fullFilePath;
            end			                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SaveUniqueTargetsToLocalFileLabel = uilabel(app.Panel_2);
            app.SaveUniqueTargetsToLocalFileLabel.Text = 'Save Unique Targets To Local File';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'C:/AstroPack/data/ULTRASAT/unique_targets.csv';
            app.EnterfilenametoloadfromorpastetextbelowLabel = uilabel(app.UIFigure);
            app.EnterfilenametoloadfromorpastetextbelowLabel.Text = 'Enter file name to load from, or paste text below, with the same format as the example below.';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveUniqueTargetsToTextFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end