classdef SelectUniqueTargetFromTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel_3                  matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        Panel_2                  matlab.ui.container.Panel
        SelectUniqueTargetLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        CancelButton             matlab.ui.control.Button
        SelectButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule      %
        Status          %
        Data            %
        Selection       %
    end
    
    methods (Access = public)
        
        function setData(app, Data)
            %
            app.Data = Data;

            % Make the columns read only
            app.UITable.ColumnEditable = false(1, width(Data));
            app.UITable.Data = Data;
            app.UITable.ColumnName = Data.Properties.VariableNames;             
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;

            %Data = [];
            %app.UITable.ColumnEditable = false(1, width(Data));
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.Status = 'Select';
            %app.MainModule.MainApp.showCalibObj(app.Selection);
            uiresume(app.UIFigure);                        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            app.Selection = app.UITable.Selection;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SelectButton = uibutton(app.Panel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SelectUniqueTargetLabel = uilabel(app.Panel_2);
            app.SelectUniqueTargetLabel.Text = 'Select Unique Target';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Title'; 'User'; 'Updated'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectUniqueTargetFromTable(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end