classdef ValidationStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ValidationHistoryMostRecentFirstPanel  matlab.ui.container.Panel
        UITableHistory                  matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        ResultsTextTab                  matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ResultsHTMLTab                  matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        ResultsTable                    matlab.ui.container.Tab
        HTML_2                          matlab.ui.control.HTML
        UITable                         matlab.ui.control.Table
        Validation120250101121212Panel  matlab.ui.container.Panel
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        ElapsedEditField                matlab.ui.control.EditField
        ElapsedEditFieldLabel           matlab.ui.control.Label
        StartedEditField                matlab.ui.control.EditField
        StartedEditFieldLabel           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        HelpButton                      matlab.ui.control.Button
        ValidationStatusHistoryLabel    matlab.ui.control.Label
    end

    methods (Static)
        function about()
            % ValidationStatus App
            %
            % This app displays validation results and history for observation plans.
            %
            % Features:
            % - Provides multiple views: text, HTML, and table formats.
            % - Displays validation history in reverse chronological order.
            % - Shows status, start time, and elapsed time for each validation entry.
        end
    end

    properties (Access = public)
        MainModule      % Reference to the main application module
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
        end

        % Callback function
        function CloseButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Callback function
        function ValidateButtonPushed(app, event)

        end

        % Selection changed function: UITableHistory
        function UITableHistorySelectionChanged(app, event)
            app.MainModule.MainApp.ValidationHelper.validationHistorySelected(app.MainModule.MainApp);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.MainModule.MainApp.showHelp('validation_status');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ValidationStatusHistoryLabel = uilabel(app.Panel_2);
            app.ValidationStatusHistoryLabel.Text = 'Validation Status & History';
            app.HelpButton = uibutton(app.Panel_2, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.HelpButton.Text = 'Help';
            app.Validation120250101121212Panel = uipanel(app.UIFigure);
            app.Validation120250101121212Panel.Title = 'Validation #1: 2025-01-01 12:12:12';
            app.StartedEditFieldLabel = uilabel(app.Validation120250101121212Panel);
            app.StartedEditFieldLabel.Text = 'Started';
            app.StartedEditField = uieditfield(app.Validation120250101121212Panel, 'text');
            app.StartedEditField.Editable = 'off';
            app.ElapsedEditFieldLabel = uilabel(app.Validation120250101121212Panel);
            app.ElapsedEditFieldLabel.Text = 'Elapsed';
            app.ElapsedEditField = uieditfield(app.Validation120250101121212Panel, 'text');
            app.ElapsedEditField.Editable = 'off';
            app.StatusEditFieldLabel = uilabel(app.Validation120250101121212Panel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.Validation120250101121212Panel, 'text');
            app.StatusEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResultsTextTab = uitab(app.TabGroup);
            app.ResultsTextTab.Title = 'Results (Text)';
            app.Label = uilabel(app.ResultsTextTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.ResultsTextTab);
            app.TextArea.Editable = 'off';
            app.ResultsHTMLTab = uitab(app.TabGroup);
            app.ResultsHTMLTab.Title = 'Results (HTML)';
            app.HTML = uihtml(app.ResultsHTMLTab);
            app.ResultsTable = uitab(app.TabGroup);
            app.ResultsTable.Title = 'Results (Table)';
            app.UITable = uitable(app.ResultsTable);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.HTML_2 = uihtml(app.ResultsTable);
            app.ValidationHistoryMostRecentFirstPanel = uipanel(app.UIFigure);
            app.ValidationHistoryMostRecentFirstPanel.Title = 'Validation History (Most Recent First)';
            app.UITableHistory = uitable(app.ValidationHistoryMostRecentFirstPanel);
            app.UITableHistory.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableHistory.RowName = {};
            app.UITableHistory.SelectionType = 'row';
            app.UITableHistory.SelectionChangedFcn = createCallbackFcn(app, @UITableHistorySelectionChanged, true);
            app.UITableHistory.Multiselect = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ValidationStatus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end