classdef SkyMapApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Button      matlab.ui.control.Button
        skymapAxes  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn1(app)

        % Load a sample image (replace 'skymap.jpg' with your actual image file)
        img = imread('c:\temp\skymap.jpg');
        
        % Display the image in the axes
        imshow(img, 'Parent', app.skymapAxes);
        
        % Add random data overlay (for demonstration purposes)
        hold(app.skymapAxes, 'on');
        randomData = rand(100, 2) * size(img, 1); % Assuming the image is square
        plot(app.skymapAxes, randomData(:,1), randomData(:,2), 'r*');
        hold(app.skymapAxes, 'off');

        % Enable zooming
        zoom(app.UIFigure, 'on');

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            fig = figure('Name', 'Image Figure');
            
            % Load and display the image
            img = imread('c:\temp\skymap.jpg');
            imshow(img, 'Parent', axes('Parent', fig));
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.skymapAxes = uiaxes(app.UIFigure);
            title(app.skymapAxes, 'Title')
            xlabel(app.skymapAxes, 'X')
            ylabel(app.skymapAxes, 'Y')
            zlabel(app.skymapAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SkyMapApp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end