classdef planner_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu_2                   matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        LayerspanelMenu              matlab.ui.container.Menu
        PlannerToolsMenu             matlab.ui.container.Menu
        AstronomicalToolsMenu        matlab.ui.container.Menu
        CalibrationobjectsMenu       matlab.ui.container.Menu
        ExternalsurveymapsMenu       matlab.ui.container.Menu
        MissionToolsMenu             matlab.ui.container.Menu
        SlewcalculatorMenu           matlab.ui.container.Menu
        SchedulingToolsMenu          matlab.ui.container.Menu
        ObservedApprovedTargetsMenu  matlab.ui.container.Menu
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SlewCalculatorApp % Description
        CalibrationObjectsApp %
    end
    
  
    methods (Access = public)
        
        function UpdateSlew(app, NewValue)
            app.EditField.Value = NewValue;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SlewcalculatorMenu
        function SlewcalculatorMenuSelected(app, event)
            if isempty(app.SlewCalculatorApp) || ~isvalid(app.SlewCalculatorApp)
                app.SlewCalculatorApp = tool_slew_calculator();
                app.SlewCalculatorApp.MainApp = app;
            end
            app.SlewCalculatorApp.UIFigure.Visible = 'on';
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.mat;*.txt', 'Data Files (*.mat, *.txt)'});
            if filename
                % Load data
                disp(['Opened: ', fullfile(pathname, filename)]);
            end
        end

        % Menu selected function: CalibrationobjectsMenu
        function CalibrationobjectsMenuSelected(app, event)
            %
            if isempty(app.CalibrationObjectsApp) || ~isvalid(app.CalibrationObjectsApp)
                app.CalibrationObjectsApp = tool_calibration_objects();
                app.CalibrationObjectsApp.MainApp = app;
            end
            app.SlewCalculatorApp.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu_2 = uimenu(app.UIFigure);
            app.FileMenu_2.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu_2);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu_2);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu_2);
            app.SaveMenu.Text = 'Save';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.LayerspanelMenu = uimenu(app.ViewMenu);
            app.LayerspanelMenu.Text = 'Layers panel';
            app.PlannerToolsMenu = uimenu(app.UIFigure);
            app.PlannerToolsMenu.Text = 'Planner Tools';
            app.AstronomicalToolsMenu = uimenu(app.PlannerToolsMenu);
            app.AstronomicalToolsMenu.Text = 'Astronomical Tools';
            app.CalibrationobjectsMenu = uimenu(app.AstronomicalToolsMenu);
            app.CalibrationobjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationobjectsMenuSelected, true);
            app.CalibrationobjectsMenu.Text = 'Calibration objects';
            app.ExternalsurveymapsMenu = uimenu(app.AstronomicalToolsMenu);
            app.ExternalsurveymapsMenu.Text = 'External survey maps';
            app.MissionToolsMenu = uimenu(app.PlannerToolsMenu);
            app.MissionToolsMenu.Text = 'Mission Tools';
            app.SlewcalculatorMenu = uimenu(app.MissionToolsMenu);
            app.SlewcalculatorMenu.MenuSelectedFcn = createCallbackFcn(app, @SlewcalculatorMenuSelected, true);
            app.SlewcalculatorMenu.Text = 'Slew calculator';
            app.SchedulingToolsMenu = uimenu(app.PlannerToolsMenu);
            app.SchedulingToolsMenu.Text = 'Scheduling Tools';
            app.ObservedApprovedTargetsMenu = uimenu(app.PlannerToolsMenu);
            app.ObservedApprovedTargetsMenu.Text = 'Observed & Approved Targets';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = planner_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end