classdef LoadFromTextFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterfilenametoloadfromfileorpastetextbelowLabel_2  matlab.ui.control.Label
        ExampleTextArea                 matlab.ui.control.TextArea
        ExampleTextAreaLabel            matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        EnterfilenametoloadfromfileorpastetextbelowLabel  matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        LoadUniqueTargetsFromFileLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CancelButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule          % 
        Status              %
        FileName            % 
        Text                %
    end

    
    methods (Access = public)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.Status = [];
            app.FileName = [];
            app.Text = [];

            FName = app.FileNameEditField.Value;
            app.Text = app.TextArea.Value;

            if ~isempty(FName)
                if isfile(FName)
                    app.FileName = FName;
                    app.Status = 'Load';
                    uiresume(app.UIFigure);
                else
                    AppUtils.msgError(app, 'Cannot find the specified file', 'File not found')
                end
            else
                uiresume(app.UIFigure);    
            end            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadUniqueTargetsFromFileLabel = uilabel(app.Panel_2);
            app.LoadUniqueTargetsFromFileLabel.Text = 'Load Unique Targets From File';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'C:/AstroPack/data/ULTRASAT/unique_targets.csv';
            app.EnterfilenametoloadfromfileorpastetextbelowLabel = uilabel(app.UIFigure);
            app.EnterfilenametoloadfromfileorpastetextbelowLabel.Text = 'Enter file name to load from file, or paste text below.';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.ExampleTextAreaLabel = uilabel(app.UIFigure);
            app.ExampleTextAreaLabel.Text = 'Example';
            app.ExampleTextArea = uitextarea(app.UIFigure);
            app.ExampleTextArea.Editable = 'off';
            app.ExampleTextArea.Value = {'RA,Dec,Name'; '321.46,-76.71,"Note: Numeric must start with text"'; '54.18,-86.81,"First"'; '247.89,-79.28,"Second"'; '321.46,-76.71,"The 12"'};
            app.EnterfilenametoloadfromfileorpastetextbelowLabel_2 = uilabel(app.UIFigure);
            app.EnterfilenametoloadfromfileorpastetextbelowLabel_2.Text = 'readtable expects uniform data types within each column, and it might misinterpret the file structure.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadFromTextFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end